classdef CageLab < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		CageLabGUIUIFigure              matlab.ui.Figure
		GridLayoutMAIN                  matlab.ui.container.GridLayout
		GridLayoutLOG                   matlab.ui.container.GridLayout
		statusLamp                      matlab.ui.control.Lamp
		StopButton_MAIN                 matlab.ui.control.Button
		PingButton_MAIN                 matlab.ui.control.Button
		StatusButton_MAIN               matlab.ui.control.Button
		subjectNameGlobal               matlab.ui.control.EditField
		IPGlobal                        matlab.ui.control.DropDown
		Image                           matlab.ui.control.Image
		Log                             matlab.ui.control.TextArea
		TabGroup                        matlab.ui.container.TabGroup
		MainTab                         matlab.ui.container.Tab
		GridLayout                      matlab.ui.container.GridLayout
		GlobalSettingsPanel             matlab.ui.container.Panel
		GridLayout2                     matlab.ui.container.GridLayout
		TabGroup2                       matlab.ui.container.TabGroup
		GeneralTab                      matlab.ui.container.Tab
		GridLayout21                    matlab.ui.container.GridLayout
		UserGuideButton                 matlab.ui.control.Button
		InitiateAlyxatsessionstartCheckBox  matlab.ui.control.CheckBox
		ChangeResourceFolderButton      matlab.ui.control.Button
		folderEditField                 matlab.ui.control.EditField
		PunishmentTimeOut               matlab.ui.control.NumericEditField
		PunishmentTimeOutEditFieldLabel  matlab.ui.control.Label
		DebugModeSwitch                 matlab.ui.control.Switch
		DebugModeLabel                  matlab.ui.control.Label
		DummyTouchSwitch                matlab.ui.control.Switch
		DummyTouchLabel                 matlab.ui.control.Label
		RewardPumpSwitch                matlab.ui.control.Switch
		RewardPumpLabel                 matlab.ui.control.Label
		AudioFeedbackSwitch             matlab.ui.control.Switch
		AudioLabel                      matlab.ui.control.Label
		PushtoALYXSwitch                matlab.ui.control.Switch
		PushtoALYXLabel                 matlab.ui.control.Label
		SmartBackgroundSwitch           matlab.ui.control.Switch
		SmartBackgroundLabel            matlab.ui.control.Label
		ScreenTab                       matlab.ui.container.Tab
		GridLayout20                    matlab.ui.container.GridLayout
		UseVulkanSwitch                 matlab.ui.control.Switch
		UseVulkanSwitchLabel            matlab.ui.control.Label
		HighPrioritySwitch              matlab.ui.control.Switch
		HighPrioritySwitchLabel         matlab.ui.control.Label
		HideScreenColorEditField        matlab.ui.control.EditField
		HideScreenColorEditFieldLabel   matlab.ui.control.Label
		LightsOutHourEditField          matlab.ui.control.NumericEditField
		LightsOutHourEditFieldLabel     matlab.ui.control.Label
		LightsOutSwitch                 matlab.ui.control.Switch
		LightsOutSwitchLabel            matlab.ui.control.Label
		OpenGLBlendingSwitch            matlab.ui.control.Switch
		OpenGLBlendLabel                matlab.ui.control.Label
		DisableSyncSwitch               matlab.ui.control.Switch
		DisableSyncSwitchLabel          matlab.ui.control.Label
		DefaultBackgroundColorEditField  matlab.ui.control.EditField
		DefaultBackgroundColorEditFieldLabel  matlab.ui.control.Label
		PixelDensitypxcmEditField       matlab.ui.control.NumericEditField
		PixelDensitypxcmEditFieldLabel  matlab.ui.control.Label
		DistancetoSubjectcmEditField    matlab.ui.control.NumericEditField
		DistancetoSubjectcmEditFieldLabel  matlab.ui.control.Label
		ScreenNumberEditField           matlab.ui.control.NumericEditField
		ScreenNumberEditFieldLabel      matlab.ui.control.Label
		AudioPumpTab                    matlab.ui.container.Tab
		GridLayout19                    matlab.ui.container.GridLayout
		PumpTime                        matlab.ui.control.NumericEditField
		RewardPumpTimemsLabel           matlab.ui.control.Label
		CorrectBeepHzEditField          matlab.ui.control.NumericEditField
		Label                           matlab.ui.control.Label
		IncorrectBeepHzEditField        matlab.ui.control.NumericEditField
		IncorrectBeepHzEditFieldLabel   matlab.ui.control.Label
		AudioVolumeEditField            matlab.ui.control.NumericEditField
		AudioVolumeEditFieldLabel       matlab.ui.control.Label
		AudioDeviceEditField            matlab.ui.control.EditField
		AudioDeviceEditFieldLabel       matlab.ui.control.Label
		TouchPanelTab                   matlab.ui.container.Tab
		GridLayout10                    matlab.ui.container.GridLayout
		VerboseCheckBox                 matlab.ui.control.CheckBox
		TouchDeviceNameEditField        matlab.ui.control.EditField
		TouchDeviceNameEditFieldLabel   matlab.ui.control.Label
		NegationbufferdegEditField      matlab.ui.control.NumericEditField
		NegationbufferdegEditFieldLabel  matlab.ui.control.Label
		ExclusionZoneEditField          matlab.ui.control.EditField
		ExclusionZoneEditFieldLabel     matlab.ui.control.Label
		IgnoreExtraTouchesCheckBox      matlab.ui.control.CheckBox
		DrainMultipleEventsCheckBox     matlab.ui.control.CheckBox
		StrictmodeCheckBox              matlab.ui.control.CheckBox
		NegateTouchWindowCheckBox       matlab.ui.control.CheckBox
		TouchDeviceIDEditField          matlab.ui.control.NumericEditField
		TouchDeviceIDEditFieldLabel     matlab.ui.control.Label
		StairCaseTab                    matlab.ui.container.Tab
		GridLayout_Staircase            matlab.ui.container.GridLayout
		TotalOnlyCorrect                matlab.ui.control.CheckBox
		EasyTrialsProbability           matlab.ui.control.NumericEditField
		EasyTrialsThresholdLabel        matlab.ui.control.Label
		TotalRewards                    matlab.ui.control.NumericEditField
		TotalTrialsRewardsLabel         matlab.ui.control.Label
		Note_3                          matlab.ui.control.Label
		CorrectThreshold                matlab.ui.control.NumericEditField
		CorrectThresholdEditFieldLabel_2  matlab.ui.control.Label
		MaxPhasesStepBack               matlab.ui.control.NumericEditField
		MaxPhasesStepBackEditFieldLabel_2  matlab.ui.control.Label
		IncorrectThreshold              matlab.ui.control.NumericEditField
		IncorrectThresholdEditFieldLabel_2  matlab.ui.control.Label
		StepForward                     matlab.ui.control.NumericEditField
		StepForwardNTrialsLabel         matlab.ui.control.Label
		CageLabRemoteControlPanel       matlab.ui.container.Panel
		GridLayout4                     matlab.ui.container.GridLayout
		RemotePortLabel                 matlab.ui.control.Label
		ButtonsGrid                     matlab.ui.container.GridLayout
		ResetServiceButton              matlab.ui.control.Button
		HideALLButton                   matlab.ui.control.Button
		SleepALLButton                  matlab.ui.control.Button
		PoweroffALLButton               matlab.ui.control.Button
		WakeUpALLButton                 matlab.ui.control.Button
		DisableTouchButton              matlab.ui.control.Button
		EnableTouchButton               matlab.ui.control.Button
		TouchTestButton                 matlab.ui.control.Button
		EnableSleepButton               matlab.ui.control.Button
		DisableSleepButton              matlab.ui.control.Button
		ShowDesktopButton               matlab.ui.control.Button
		PoweroffButton                  matlab.ui.control.Button
		ResetOBSButton                  matlab.ui.control.Button
		ResetMATLABButton               matlab.ui.control.Button
		HideDesktopButton               matlab.ui.control.Button
		AlyxLink                        matlab.ui.control.Hyperlink
		CustomCommand                   matlab.ui.control.EditField
		RmIP                            matlab.ui.control.Button
		AddIP                           matlab.ui.control.Button
		RemoteServerPortEditFieldLabel_2  matlab.ui.control.Label
		IPAddress                       matlab.ui.control.DropDown
		RemoteCommand                   matlab.ui.control.ListBox
		RunCommandButton                matlab.ui.control.Button
		PingButton                      matlab.ui.control.Button
		RemoteControlSwitch             matlab.ui.control.Switch
		RemoteControlSwitchLabel        matlab.ui.control.Label
		RemotePort                      matlab.ui.control.NumericEditField
		AlyxTab                         matlab.ui.container.Tab
		GridLayout11                    matlab.ui.container.GridLayout
		ALYXInterfacePanel              matlab.ui.container.Panel
		AlyxLayout                      matlab.ui.container.GridLayout
		alyxGUIOpen                     matlab.ui.control.Button
		SendDatabaseRequestLabel        matlab.ui.control.Label
		OKAlyxRequest                   CompactEditableComboBox
		text4554_8                      matlab.ui.control.Label
		text4554_7                      matlab.ui.control.Label
		OKDataBucket                    matlab.ui.control.EditField
		OKDataRepo                      matlab.ui.control.EditField
		ChangeButton                    matlab.ui.control.Button
		PasswordReset                   matlab.ui.control.DropDown
		SessionMetadataLabel            matlab.ui.control.Label
		AlyxAddWater                    matlab.ui.control.Button
		SubjectWater                    matlab.ui.control.EditField
		AlyxAddWeight                   matlab.ui.control.Button
		SubjectWeight                   matlab.ui.control.EditField
		AlyxDatastoreDatabaseLabel      matlab.ui.control.Label
		text4554_6                      matlab.ui.control.Label
		OKLabLocation                   matlab.ui.control.EditField
		text209_2                       matlab.ui.control.Label
		OKLabName                       matlab.ui.control.EditField
		text4554_5                      matlab.ui.control.Label
		OKResearcher                    matlab.ui.control.EditField
		text209                         matlab.ui.control.Label
		OKSubject                       matlab.ui.control.EditField
		ProcedureLabel                  matlab.ui.control.Label
		alyxUser                        matlab.ui.control.EditField
		alyxIP                          matlab.ui.control.EditField
		OKAlyxTaskProtocol              matlab.ui.control.EditField
		TaskProtocolEditFieldLabel      matlab.ui.control.Label
		text4554_4                      matlab.ui.control.Label
		OKAlyxBrainRegion               matlab.ui.control.DropDown
		text4554_3                      matlab.ui.control.Label
		OKAlyxProject                   matlab.ui.control.DropDown
		OKAlyxProcedure                 matlab.ui.control.DropDown
		OKAlyxLOGINButton               matlab.ui.control.Button
		OKAlyxLOGOUTButton              matlab.ui.control.Button
		OKAlyxRequestButton             matlab.ui.control.Button
		OKAlyxResults                   matlab.ui.control.Table
		text4554_2                      matlab.ui.control.Label
		text4554                        matlab.ui.control.Label
		TouchTab                        matlab.ui.container.Tab
		GridLayout12                    matlab.ui.container.GridLayout
		AutomatedTouchTrainingPanel     matlab.ui.container.Panel
		GridLayout9                     matlab.ui.container.GridLayout
		rewardnote_2                    matlab.ui.control.Label
		InterTrialTime_TOUCH            matlab.ui.control.NumericEditField
		TrialTimeEditField_8Label_2     matlab.ui.control.Label
		StatusButton                    matlab.ui.control.Button
		rewardnote                      matlab.ui.control.Label
		StopButton_TOUCH                matlab.ui.control.Button
		STARTButton_TOUCH               matlab.ui.control.Button
		TargetType_TOUCH                matlab.ui.control.DropDown
		TargetTypeDropDownLabel         matlab.ui.control.Label
		Difficulty_TOUCH                matlab.ui.control.DropDown
		DifficultyDropDownLabel         matlab.ui.control.Label
		TrialTime_TOUCH                 matlab.ui.control.NumericEditField
		TrialTimeEditFieldLabel         matlab.ui.control.Label
		TargetDiscColor_TOUCH           matlab.ui.control.EditField
		TargetDiscColorEditFieldLabel   matlab.ui.control.Label
		RandomRewardProbability_TOUCH   matlab.ui.control.NumericEditField
		RandomRewardProbabilityEditFieldLabel  matlab.ui.control.Label
		RandomRewardTimer_TOUCH         matlab.ui.control.NumericEditField
		RandomRewardTimerEditFieldLabel  matlab.ui.control.Label
		FinalTargetSize_TOUCH           matlab.ui.control.NumericEditField
		FinalTargetSizeEditFieldLabel   matlab.ui.control.Label
		InitialTargetSize_TOUCH         matlab.ui.control.NumericEditField
		InitialTargetSizeEditFieldLabel  matlab.ui.control.Label
		Phase_TOUCH                     matlab.ui.control.NumericEditField
		Phase138Label                   matlab.ui.control.Label
		MTSTab                          matlab.ui.container.Tab
		GridLayout13                    matlab.ui.container.GridLayout
		NonMatchToSampleMTSPanel        matlab.ui.container.Panel
		GridLayout9_2                   matlab.ui.container.GridLayout
		NonMatch_MTS                    matlab.ui.control.Switch
		NonMatchLabel_2                 matlab.ui.control.Label
		StatusButton_MTS                matlab.ui.control.Button
		TargetHoldTime_MTS              matlab.ui.control.NumericEditField
		TargetHoldTimeEditFieldLabel    matlab.ui.control.Label
		InitiationHoldTime_MTS          matlab.ui.control.NumericEditField
		InitiationHoldTimeEditFieldLabel  matlab.ui.control.Label
		DistractorYPosition_MTS         matlab.ui.control.NumericEditField
		DistractorYPositionEditFieldLabel  matlab.ui.control.Label
		SampleYPosition_MTS             matlab.ui.control.NumericEditField
		SampleYPositionEditFieldLabel   matlab.ui.control.Label
		InitiationPointSize_MTS         matlab.ui.control.NumericEditField
		InitiationPointSizeEditField_2Label  matlab.ui.control.Label
		InitiationPointColour_MTS       matlab.ui.control.EditField
		InitiationPointColourEditField_2Label  matlab.ui.control.Label
		InitiationPointPositionxy_MTS   matlab.ui.control.EditField
		InitiationPointPositionxyEditField_2Label  matlab.ui.control.Label
		StopButton_MTS                  matlab.ui.control.Button
		STARTMTSButton                  matlab.ui.control.Button
		ObjectType_MTS                  matlab.ui.control.DropDown
		ObjectTypeDropDownLabel         matlab.ui.control.Label
		TaskStage_MTS                   matlab.ui.control.DropDown
		TaskStageDropDownLabel          matlab.ui.control.Label
		TrialTime_MTS                   matlab.ui.control.NumericEditField
		TrialTimeEditField_2Label       matlab.ui.control.Label
		ObjectSeparation_MTS            matlab.ui.control.NumericEditField
		ObjectSeparationEditFieldLabel  matlab.ui.control.Label
		ObjectSize_MTS                  matlab.ui.control.NumericEditField
		ObjectSizeEditFieldLabel        matlab.ui.control.Label
		Numberofdistractors_MTS         matlab.ui.control.NumericEditField
		NumberofdistractorsEditFieldLabel  matlab.ui.control.Label
		DMTSTab                         matlab.ui.container.Tab
		GridLayout14                    matlab.ui.container.GridLayout
		DelayedNonMatchToSampleDMTSPanel  matlab.ui.container.Panel
		GridLayout9_5                   matlab.ui.container.GridLayout
		DistractorCenterAngle_DMTS      matlab.ui.control.NumericEditField
		TargetYPositionLabel_6          matlab.ui.control.Label
		DistractorAngleSpread_DMTS      matlab.ui.control.NumericEditField
		TargetYPositionLabel_5          matlab.ui.control.Label
		DelayDistractors_DMTS           matlab.ui.control.Switch
		AddDelayPeriodDistractorsLabel  matlab.ui.control.Label
		StatusButton_3                  matlab.ui.control.Button
		TargetHoldTime_DMTS             matlab.ui.control.NumericEditField
		TargetHoldTimesLabel_8          matlab.ui.control.Label
		InitiationHoldTimesLabel_2      matlab.ui.control.Label
		SampleDisplayTime_DMTS          matlab.ui.control.EditField
		SampleTimexorminmaxLabel        matlab.ui.control.Label
		NonMatch_DMTS                   matlab.ui.control.Switch
		NonMatchLabel                   matlab.ui.control.Label
		DelayTime_DMTS                  matlab.ui.control.EditField
		DelayTimexorminmaxLabel         matlab.ui.control.Label
		DistractorYPosition_DMTS        matlab.ui.control.NumericEditField
		DistractorYPositionLabel        matlab.ui.control.Label
		SampleYPosition_DMTS            matlab.ui.control.NumericEditField
		SampleYPositionLabel            matlab.ui.control.Label
		InitSize_DMTS                   matlab.ui.control.NumericEditField
		InitiationPointSizeLabel        matlab.ui.control.Label
		InitHoldTime_DMTS               matlab.ui.control.NumericEditField
		InitColour_DMTS                 matlab.ui.control.EditField
		InitiationPointColourEditField_3Label  matlab.ui.control.Label
		InitPosition_DMTS               matlab.ui.control.EditField
		InitiationPointPositionxyLabel  matlab.ui.control.Label
		StopButton_DMTS                 matlab.ui.control.Button
		STARTDMTSButton                 matlab.ui.control.Button
		ObjectType_DMTS                 matlab.ui.control.DropDown
		ObjectTypeDropDown_4Label       matlab.ui.control.Label
		TaskStage_DMTS                  matlab.ui.control.DropDown
		TaskStageDropDown_4Label        matlab.ui.control.Label
		TrialTime_DMTS                  matlab.ui.control.NumericEditField
		TrialTimesLabel                 matlab.ui.control.Label
		ObjectSeparation_DMTS           matlab.ui.control.NumericEditField
		ObjectSeparationdegLabel        matlab.ui.control.Label
		ObjectSize_DMTS                 matlab.ui.control.NumericEditField
		ObjectSizeLabel                 matlab.ui.control.Label
		Numberofdistractors_DMTS        matlab.ui.control.NumericEditField
		NumberofdistractorsEditField_2Label  matlab.ui.control.Label
		THINGSTab                       matlab.ui.container.Tab
		GridLayout13_2                  matlab.ui.container.GridLayout
		THINGSOddOneOutOOOTaskPanel     matlab.ui.container.Panel
		GridLayout9_8                   matlab.ui.container.GridLayout
		DistractorCenterAngle_OOO       matlab.ui.control.NumericEditField
		TargetYPositionLabel_2          matlab.ui.control.Label
		DistractorAngleSpread_OOO       matlab.ui.control.NumericEditField
		ObjectSeparation_OOO            matlab.ui.control.NumericEditField
		simplified_OOO                  matlab.ui.control.CheckBox
		easyMode_OOO                    matlab.ui.control.CheckBox
		UseStaircase_OOO                matlab.ui.control.CheckBox
		Phase_OOO                       matlab.ui.control.NumericEditField
		Phase138Label_2                 matlab.ui.control.Label
		PedestalOpacity_OOO             matlab.ui.control.NumericEditField
		TargetHoldTimesLabel_7          matlab.ui.control.Label
		DistractorOpacity_OOO           matlab.ui.control.NumericEditField
		TargetHoldTimesLabel_6          matlab.ui.control.Label
		CatchTrials_OOO                 matlab.ui.control.NumericEditField
		TargetHoldTimesLabel_5          matlab.ui.control.Label
		RepeatTrialsPercent_OOO         matlab.ui.control.NumericEditField
		TargetHoldTimesLabel_4          matlab.ui.control.Label
		equalizeN_OOO_2                 matlab.ui.control.NumericEditField
		TargetHoldTimesLabel_3          matlab.ui.control.Label
		equalizeN_OOO                   matlab.ui.control.NumericEditField
		TargetHoldTimesLabel_2          matlab.ui.control.Label
		folder_OOO                      matlab.ui.control.EditField
		ChangeResource_OOO              matlab.ui.control.Button
		StatusButton_OOO                matlab.ui.control.Button
		TargetHoldTime_OOO              matlab.ui.control.NumericEditField
		TargetHoldTimesLabel            matlab.ui.control.Label
		InitiationHoldTime_OOO          matlab.ui.control.NumericEditField
		InitiationHoldTimesLabel        matlab.ui.control.Label
		TargetYPositionLabel            matlab.ui.control.Label
		InitiationPointSize_OOO         matlab.ui.control.NumericEditField
		InitiationPointSizeEditField_2Label_2  matlab.ui.control.Label
		InitiationPointColour_OOO       matlab.ui.control.EditField
		InitiationPointColourEditField_2Label_2  matlab.ui.control.Label
		InitiationPointPositionxy_OOO   matlab.ui.control.EditField
		InitiationPointPositionxyEditField_2Label_2  matlab.ui.control.Label
		StopButton_OOO                  matlab.ui.control.Button
		STARTTHINGSButton               matlab.ui.control.Button
		ObjectType_OOO                  matlab.ui.control.DropDown
		ObjectSetLabel                  matlab.ui.control.Label
		TaskStage_OOO                   matlab.ui.control.DropDown
		TaskStageDropDownLabel_2        matlab.ui.control.Label
		TrialTime_OOO                   matlab.ui.control.NumericEditField
		TrialTimeEditField_2Label_2     matlab.ui.control.Label
		ObjectDistanceLabel             matlab.ui.control.Label
		ObjectSize_OOO                  matlab.ui.control.NumericEditField
		ObjectSizeEditFieldLabel_2      matlab.ui.control.Label
		PacmanTask                      matlab.ui.container.Tab
		GridLayout15                    matlab.ui.container.GridLayout
		PacmanTaskPMTPanel              matlab.ui.container.Panel
		GridLayout9_6                   matlab.ui.container.GridLayout
		JoyStickDelay_PMT               matlab.ui.control.NumericEditField
		Phase138Label_3                 matlab.ui.control.Label
		MapsPath_PMT                    matlab.ui.control.EditField
		GetPath_PMT                     matlab.ui.control.Button
		ForceResolution_PMT             matlab.ui.control.Switch
		ForceResolutionLabel            matlab.ui.control.Label
		RewardBeeps_PMT                 matlab.ui.control.Switch
		RewardBeepsSwitchLabel          matlab.ui.control.Label
		Note_PMT                        matlab.ui.control.Label
		StatusButton_PMT                matlab.ui.control.Button
		StopButton_PMT                  matlab.ui.control.Button
		STARTPMTButton                  matlab.ui.control.Button
		Map_PMT                         matlab.ui.control.DropDown
		MapLabel                        matlab.ui.control.Label
		IED                             matlab.ui.container.Tab
		GridLayout_IED                  matlab.ui.container.GridLayout
		IntraExtraDimensionalSetShiftingIEDPanel  matlab.ui.container.Panel
		GridLayout_IED2                 matlab.ui.container.GridLayout
		ObjectSeparation_IED            matlab.ui.control.NumericEditField
		ObjectDistanceLabel_2           matlab.ui.control.Label
		ObjectSize_IED                  matlab.ui.control.NumericEditField
		ObjectSizeEditFieldLabel_3      matlab.ui.control.Label
		DistractorCenterAngle_IED       matlab.ui.control.NumericEditField
		TargetYPositionLabel_4          matlab.ui.control.Label
		DistractorAngleSpread_IED       matlab.ui.control.NumericEditField
		TargetYPositionLabel_3          matlab.ui.control.Label
		InitHoldTime_IED                matlab.ui.control.NumericEditField
		InitiationHoldTimesLabel_3      matlab.ui.control.Label
		InitSize_IED                    matlab.ui.control.NumericEditField
		InitiationPointSizeLabel_2      matlab.ui.control.Label
		InitColour_IED                  matlab.ui.control.EditField
		EditFieldLabel_2                matlab.ui.control.Label
		InitPosition_IED                matlab.ui.control.EditField
		InitPosition_IEDLabel           matlab.ui.control.Label
		distractors_IED                 matlab.ui.control.CheckBox
		randomiseDistractors_IED        matlab.ui.control.CheckBox
		distractorTwo_IED               matlab.ui.control.NumericEditField
		Phase138Label_7                 matlab.ui.control.Label
		distractorOne_IED               matlab.ui.control.NumericEditField
		Phase138Label_6                 matlab.ui.control.Label
		MaxIncorrect_IED                matlab.ui.control.NumericEditField
		Phase138Label_5                 matlab.ui.control.Label
		criterion_IED                   matlab.ui.control.NumericEditField
		Phase138Label_4                 matlab.ui.control.Label
		ED_IED                          matlab.ui.control.DropDown
		TaskStageDropDownLabel_8        matlab.ui.control.Label
		TaskType_IED                    matlab.ui.control.EditField
		TaskType_IEDLabel               matlab.ui.control.Label
		ID_IED                          matlab.ui.control.DropDown
		TaskStageDropDownLabel_7        matlab.ui.control.Label
		Targets_IED                     matlab.ui.control.DropDown
		TaskStageDropDownLabel_6        matlab.ui.control.Label
		ObjectType_IED                  matlab.ui.control.DropDown
		ObjectSetLabel_2                matlab.ui.control.Label
		TaskStage_IED                   matlab.ui.control.DropDown
		TaskStageDropDownLabel_3        matlab.ui.control.Label
		Note_IED                        matlab.ui.control.Label
		StatusButton_IED                matlab.ui.control.Button
		StopButton_IED                  matlab.ui.control.Button
		STARTIEDButton                  matlab.ui.control.Button
		TBIPTab                         matlab.ui.container.Tab
		TimeBasedProtocolPanel          matlab.ui.container.Panel
		GridLayout9_10                  matlab.ui.container.GridLayout
		TaskStage_IED_4                 matlab.ui.control.DropDown
		StateMachineTaskLabel           matlab.ui.control.Label
		TaskStage_IED_2                 matlab.ui.control.DropDown
		TaskStageDropDownLabel_4        matlab.ui.control.Label
		RewardBeeps2_TBIP               matlab.ui.control.Switch
		EyeLinkLabel                    matlab.ui.control.Label
		RewardBeeps_TBIP                matlab.ui.control.Switch
		RewardBeepsSwitchLabel_3        matlab.ui.control.Label
		Note_TBIP                       matlab.ui.control.Label
		StatusButton_TBIP               matlab.ui.control.Button
		StopButton_TBIP                 matlab.ui.control.Button
		STARTTBIPButton                 matlab.ui.control.Button
		OddballTab                      matlab.ui.container.Tab
		TimeBasedProtocolPanel_2        matlab.ui.container.Panel
		GridLayout9_11                  matlab.ui.container.GridLayout
		TaskStage_IED_3                 matlab.ui.control.DropDown
		TaskStageDropDownLabel_5        matlab.ui.control.Label
		RewardBeeps_TBIP_2              matlab.ui.control.Switch
		RewardBeepsSwitchLabel_4        matlab.ui.control.Label
		Note_TBIP_2                     matlab.ui.control.Label
		StatusButton_TBIP_2             matlab.ui.control.Button
		StopButton_TBIP_2               matlab.ui.control.Button
		STARTOddballButton              matlab.ui.control.Button
		GoukiCATTab                     matlab.ui.container.Tab
		GridLayout16                    matlab.ui.container.GridLayout
		DragCategorisationDCATPanel     matlab.ui.container.Panel
		GridLayout9_4                   matlab.ui.container.GridLayout
		StatusButton_4                  matlab.ui.control.Button
		StopButton_8                    matlab.ui.control.Button
		InitiationPointSizeEditField    matlab.ui.control.NumericEditField
		InitiationPointSizeEditFieldLabel  matlab.ui.control.Label
		ObjectSizeEditField_2           matlab.ui.control.NumericEditField
		ObjectSizeEditField_2Label      matlab.ui.control.Label
		TargetPosition2EditField        matlab.ui.control.EditField
		TargetPosition2EditFieldLabel   matlab.ui.control.Label
		TargetPosition1EditField        matlab.ui.control.EditField
		TargetPosition1EditFieldLabel   matlab.ui.control.Label
		InitiationPointColourEditField  matlab.ui.control.EditField
		InitiationPointColourEditFieldLabel  matlab.ui.control.Label
		STARTDCATButton                 matlab.ui.control.Button
		ObjectTypeDropDown_3            matlab.ui.control.DropDown
		ObjectTypeDropDown_3Label       matlab.ui.control.Label
		TaskStageDropDown_3             matlab.ui.control.DropDown
		TaskStageDropDown_3Label        matlab.ui.control.Label
		TrialTimeEditField_7            matlab.ui.control.NumericEditField
		TrialTimeEditField_7Label       matlab.ui.control.Label
		InitiationPointPositionxyEditField  matlab.ui.control.EditField
		InitiationPointPositionxyEditFieldLabel  matlab.ui.control.Label
		InterTrialIntervalEditField     matlab.ui.control.NumericEditField
		InterTrialIntervalEditFieldLabel  matlab.ui.control.Label
		ICATab                          matlab.ui.container.Tab
		GridLayout17                    matlab.ui.container.GridLayout
		PatchForagingTab                matlab.ui.container.Tab
		GridLayout17_2                  matlab.ui.container.GridLayout
		ThresholdsTab                   matlab.ui.container.Tab
		GridLayout17_3                  matlab.ui.container.GridLayout
		ContextMenu                     matlab.ui.container.ContextMenu
		sessionssubjecttest02Menu       matlab.ui.container.Menu
		weighingslimit10Menu            matlab.ui.container.Menu
		subjectsMenu                    matlab.ui.container.Menu
	end


	properties (Access = private)
		uiPrefsList cell = {'IPAddress', 'RemoteServerPortEditField',...
			'ScreenNumberEditField',...
			'alyxIP', 'alyxUser',...
			'StepForward', 'CorrectThreshold', 'IncorrectThreshold',...
			'MaxPhasesStepBack','EasyTrialsProbability','TotalRewards',...
			'OKAlyxRequest',...
			'UseALYXSwitch',...
			'OKResearcher', 'OKLabName', 'OKSubject', 'OKLabLocation',...
			'OKDataBucket','OKDataRepo',...
			'CustomCommand',...
			'LightsOutSwitch','DisableSyncSwitch','OpenGLBlendSwitch',...
			'ScreenEditField', 'PixelDensitypxcmEditField', 'DistancecmEditField',...
			'LightsOutHourEditField',...
			'AudioDeviceEditField', 'IncorrectBeepEditField', 'CorrectBeepEditField',...
			'AudioVolumeEditField',...
			'PumpTime', ...
			'PunishmentTimeOut', ...
			'folderEditField',...
			'TouchDeviceIDEditField','TouchDeviceNameEditField',...
			'NegationbufferdegEditField', 'ExclusionZoneEditField', 'DrainMultipleEventsCheckBox',...
			'Phase_TOUCH','StepForward_TOUCH','CorrectThreshold_TOUCH','IncorrectThreshold_TOUCH',...
			'TotalRewards_TOUCH','MaxPhasesStepBack_TOUCH', 'Difficulty_TOUCH', 'TargetType_TOUCH',...
			'InitialTargetSize_TOUCH','FinalTargetSize_TOUCH','TrialTime_TOUCH','InterTrialTime_TOUCH',...
			'TargetHoldTime_DMTS','InitiationHoldTime_DMTS','SampleDisplayTime_DMTS','DelayTime_DMTS',...
			'DistractorYPosition_DMTS','SampleYPosition_DMTS','ObjectSeparation_DMTS','ObjectSize_DMTS',...
			'Numberofdistractors_DMTS','NonMatch_DMTS',...
			'TargetHoldTime_MTS','InitiationHoldTime_MTS','SampleDisplayTime_MTS','DelayTime_MTS',...
			'DistractorYPosition_MTS','SampleYPosition_MTS','ObjectSeparation_MTS','ObjectSize_MTS',...
			'Numberofdistractors_MTS','NonMatch_MTS',...
			'MapsPath_PMT','ForceResolution_PMT','RewardBeeps_PMT','JoyStickDelay_PMT'...
			}
		statusTimer timer
	end

	properties (Access = public)
		jzmq % zeromq connection class
		conductorID = [] % uuid for the conductor from exps endpoint
		alyx % alyx manager class
		session % session data
		status % cogmoteGO status class
	end

	methods (Access = private)

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function opts = generalOptions(app,opts)
			arguments
				app
				opts = []
			end

			opts.alyx = app.alyx;
			opts.zmq = app.jzmq;
			opts.alyxLink = app.AlyxLink;
			opts.session = [];

			opts.phase = 1;

			opts.remote = app.RemoteControlSwitch.Value;
			opts.IP = strip(app.IPAddress.Value);
			opts.port = app.RemotePort.Value;
			opts.screen = app.ScreenNumberEditField.Value;
			opts.distance = app.DistancetoSubjectcmEditField.Value;
			opts.density = app.PixelDensitypxcmEditField.Value;
			opts.bg = eval(app.DefaultBackgroundColorEditField.Value);
			opts.hideColor = eval(app.HideScreenColorEditField.Value);
			opts.disableSync = app.DisableSyncSwitch.Value;
			opts.useBlending = app.OpenGLBlendingSwitch.Value;
			opts.highPriority = app.HighPrioritySwitch.Value;
			opts.useVulkan = app.UseVulkanSwitch.Value;
			opts.smartBackground = app.SmartBackgroundSwitch.Value;

			opts.audio = app.AudioFeedbackSwitch.Value;
			opts.audioDevice = app.AudioDeviceEditField.Value;
			opts.audioVolume = app.AudioVolumeEditField.Value;
			opts.correctBeep = app.CorrectBeepHzEditField.Value;
			opts.incorrectBeep = app.IncorrectBeepHzEditField.Value;

			opts.folder = strip(app.folderEditField.Value);
			opts.dummy = app.DummyTouchSwitch.Value;
			opts.debug = app.DebugModeSwitch.Value;

			opts.timeOut = app.PunishmentTimeOut.Value;

			GetAlyxSettings(app);
			opts.session = app.session;
			opts.useAlyx = app.PushtoALYXSwitch.Value;
			opts.initAlyxAtStart = logical(app.InitiateAlyxatsessionstartCheckBox.Value);
			opts.name = app.session.subjectName;
			opts.researcher = app.session.researcherName;
			opts.lab = app.session.labName;
			opts.location = app.session.location;

			opts.reward = app.RewardPumpSwitch.Value;
			opts.rewardTime = app.PumpTime.Value;
			opts.rewardPort = '';

			opts.stepForward = app.StepForward.Value;
			opts.stepPercent = app.CorrectThreshold.Value;
			opts.stepBackPercent = app.IncorrectThreshold.Value;
			opts.totalTrials = app.TotalRewards.Value;
			opts.phaseMaxBack = app.MaxPhasesStepBack.Value;
			opts.totalOnlyCorrect = logical(app.TotalOnlyCorrect.Value);

			opts.negationBuffer = app.NegationbufferdegEditField.Value;
			opts.exclusionZone = eval(app.ExclusionZoneEditField.Value);
			opts.trackID = logical(app.IgnoreExtraTouchesCheckBox.Value);
			opts.drainEvents = app.DrainMultipleEventsCheckBox.Value;
			opts.verbose = app.VerboseCheckBox.Value;
			opts.strictMode = app.StrictmodeCheckBox.Value;
			opts.doNegation = app.NegateTouchWindowCheckBox.Value;
			opts.touchDevice = app.TouchDeviceIDEditField.Value;
			opts.touchDeviceName = strip(app.TouchDeviceNameEditField.Value);

			opts.useStaircase = true;
			opts.sampleY = -1;
			opts.initSize = 5;
			opts.initPosition = [0 0];
			opts.trialTime = 5;
			opts.ITI = 1;
			opts.delayTime = 0.3;
			opts.sampleTime = 0.3;
			opts.delayDistractors = false;
			opts.trainingSet = '';
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function GetAlyxSettings(app)
			app.session.initialised = false;
			app.session.sessionURL = [];
			app.session.subjectName = strip(app.OKSubject.Value);
			app.session.labName = strip(app.OKLabName.Value);
			app.session.researcherName = strip(app.OKResearcher.Value);
			app.session.location = strip(app.OKLabLocation.Value);
			app.session.brainRegion = strip(app.OKAlyxBrainRegion.Value);
			app.session.procedure = strip(app.OKAlyxProcedure.Value);
			app.session.project = strip(app.OKAlyxProject.Value);
			app.session.IP = strip(app.alyxIP.Value);
			app.session.alyxUser = strip(app.alyxUser.Value);
			app.session.useAlyx = app.PushtoALYXSwitch.Value;
			app.session.dataURL = strip(app.OKDataRepo.Value);
			app.session.dataBucket = strip(app.OKDataBucket.Value);
			if isempty(app.alyx) || ~isa(app.alyx,'alyxManager')
				app.alyx = alyxManager;
			end
			if matches(app.PushtoALYXSwitch.Value,'On')
				app.alyx.setSecrets([],[],[],true);
			end
			app.alyx.baseURL = app.session.IP;
			app.alyx.subject = app.session.subjectName;
			app.alyx.user = app.session.alyxUser;
			app.alyx.lab = app.session.labName;
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function opts = fixOptions(~, opts)
			fn = fieldnames(opts);
			for i = 1:length(fn)
				if ischar(opts.(fn{i})) && matches(opts.(fn{i}),'On')
					opts.(fn{i}) = true;
				elseif ischar(opts.(fn{i})) && matches(opts.(fn{i}),'Off')
					opts.(fn{i}) = false;
				end
			end
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function loadPrefs(app)
			if ~ispref('cagelab'); return; end
			tt = tic;
			anyLoaded = false; prefnames = ""; a = 1;
			fn = fieldnames(app);
			for i = 1:length(fn)
				prfname = fn{i};
				if ispref('cagelab',prfname) %pref exists
					if isprop(app, prfname) %ui widget exists
						myhandle = app.(prfname);
						prf = getpref('cagelab', prfname);
						uiType = myhandle.Type;
						thisVal = '';
						switch uiType
							case 'uieditfield'
								if ischar(prf)
									myhandle.Value = prf;
									thisVal = prf;
								else
									myhandle.Value = num2str(prf);
									thisVal = myhandle.Value;
								end
							case 'uinumericeditfield'
								myhandle.Value = prf;
								thisVal = num2str(prf);
							case 'uicheckbox'
								if islogical(prf) || isnumeric(prf)
									myhandle.Value = prf;
									thisVal = num2str(prf);
								end
							case {'uidropdown','compacteditablecombobox'}
								str = myhandle.Items;
								if ischar(prf) && any(contains(prf, str))
									myhandle.Value = prf;
									thisVal = prf;
								elseif iscell(prf) & length(prf)==2
									myhandle.Items = prf{1};
									myhandle.Value = prf{2};
									thisVal = prf{2};
								end
							case 'uimenu'
								myhandle.Checked = prf;
								thisVal = char(prf);
							case 'uirockerswitch'
								if strcmpi(prf,'on') || strcmpi(prf,'off')
									myhandle.Value = prf;
									thisVal = prf;
								end
						end
						prefnames = [prefnames " " prfname " «" thisVal "»"];
						if ~mod(a,3); prefnames = [prefnames "\n"]; end
						if ~anyLoaded; anyLoaded = true; end
						a = a + 1;
					end
				end
			end
			if anyLoaded
				fprintf('\n≣≣≣≣⊱ CageLab Load Prefs in %.2f secs:\n',toc(tt)); fprintf(join(prefnames)); fprintf('\n');
			end
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function savePrefs(app)
			if ispref('cagelab'); rmpref('cagelab'); end
			tt = tic;
			anySaved = false; prefnames = ""; a = 1;
			for i = 1:length(app.uiPrefsList)
				prf = [];
				prfname = app.uiPrefsList{i};
				if ~isprop(app,prfname); continue; end
				try
					myhandle = app.(prfname);
					uiType = myhandle.Type;
					switch uiType
						case {'uinumericeditfield','uieditfield','uirockerswitch'}
							prf = myhandle.Value;
						case {'uidropdown','compacteditablecombobox'}
							prf = {myhandle.Items, myhandle.Value};
						case 'uicheckbox'
							prf = myhandle.Value;
							if ~islogical(prf); prf=logical(prf);end
						case 'uimenu'
							prf = myhandle.Checked;
					end
					if ~isempty(prf)
						setpref('cagelab', prfname, prf);
						prefnames = [prefnames " " prfname " «"  strip(formattedDisplayText(prf)) "»"];
						if ~mod(a,3); prefnames = [prefnames "\n"]; end
						a = a + 1;
					end
					if ~anySaved; anySaved = true; end
				end
			end
			if anySaved; fprintf('\n≣≣≣≣⊱ CageLab Save Prefs in %.2f secs:\n',toc(tt)); fprintf(join(prefnames)); fprintf('\n');end
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function showAlyxTable(app,data)
			tbl = [];
			try
				tbl = struct2table(data,'asArray',true);
			catch ME
				postToLog(app,'Cannot show data as a table...','Warning');
				return
			end
			if ~isempty(tbl)
				app.OKAlyxResults.Data = tbl;
				app.OKAlyxResults.ColumnName = tbl.Properties.VariableNames;
				app.OKAlyxResults.Enable = "on";
			else
				app.OKAlyxResults.Data = table("Unknown error",'VariableNames',{'Error'});
				app.OKAlyxResults.ColumnName = {'Error'};
				app.OKAlyxResults.Enable = "off";
			end
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function checkPasswords(app)
			if strcmpi(app.PushtoALYXSwitch.Value, 'off'); return; end
			secrets = getSecrets(app.alyx);
			if isempty(secrets.password)
				s = '';
				try s = getSecret('AlyxPassword'); end
				if isempty(s); s = getenv('AlyxPassword'); end
				if isempty(s)
					app.alyx.secretUI('password')
				else
					app.alyx.setSecrets(s);
				end
			end
			if isempty(secrets.AWS_ID)
				s = '';
				try s = getSecret('AWS_ID'); end
				if isempty(s); s = getenv('AWS_ID'); end
				if isempty(s)
					app.alyx.secretUI('AWS_ID')
				else
					app.alyx.setSecrets([],s);
				end
			end
			if isempty(secrets.AWS_KEY)
				s = '';
				try s = getSecret('AWS_KEY'); end
				if isempty(s); s = getenv('AWS_KEY'); end
				if isempty(s)
					app.alyx.secretUI('AWS_KEY')
				else
					app.alyx.setSecrets([],[],s);
				end
			end
		end

	end

	methods (Access = public)

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function postToLog(app, msg, prefix)
			if isempty(app.Log.Value)
				app.Log.Value = "=== === Log Start @ "+string(datetime)+" === ===";
			end
			if ~exist('prefix','var'); prefix = []; end
			if isstruct(msg)
				if isfield(msg,'message')
					msg = string(msg.message);
				else
					msg = formattedDisplayText(msg);
				end
			elseif iscell(msg)
				msg = formattedDisplayText(msg);
			else
				msg = string(msg);
			end
			if length(char(msg)) > 800
				msg = char(msg);
				msg = msg(1:800);
				msg = string(msg);
			end
			if ~isempty(prefix)
				app.Log.Value = [app.Log.Value; "----------="+string(datetime)+"=----------"; prefix; strip(msg,'right')];
			else
				app.Log.Value = [app.Log.Value; "----------="+string(datetime)+"=----------"; strip(msg,'right')];
			end
			scroll(app.Log,"bottom");
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function [response, data] = sendCmd(app, cmd, data)
			arguments(Input)
				app
				cmd string
				data = []
			end
			apiEndpoint = ['http://' strtok(app.IPAddress.Value)  ':' num2str(app.RemotePort.Value) '/api/cmds/proxies/matlab'];
			[response, data] = app.jzmq.sendViaProxy(cmd, data, apiEndpoint);
			postToLog(app, sprintf('Sent Command: %s',cmd));
			postToLog(app, response,"Reply:");
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function response = getEndpoint(app, endpoint, postError)
			arguments(Input)
				app
				endpoint {mustBeNonmissing, mustBeNonempty}
				postError = true
			end
			options = weboptions('ContentType', 'json', 'CharacterEncoding', 'UTF-8');
			apiEndpoint = ['http://' strtok(app.IPAddress.Value)  ':' num2str(app.RemotePort.Value) '/' endpoint];
			try
				response = webread(apiEndpoint,options);
				if isstruct(response)
					if isfield(response,'message')
						rt = string(response.message);
					else
						rt = formattedDisplayText(response);
					end
					app.postToLog(string(sprintf('\ngetEndpoint @%s to %s\nRESPONSE:\n%s', string(datetime()), endpoint, rt)));
				end
			catch ME
				response = ME.identifier;
				if postError
					app.postToLog(sprintf('getEndpoint "%s" failed - disable any proxies - error: %s - %s', endpoint, ME.identifier, ME.message));
				end
			end
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function [response, data] = postEndpoint(app, endpoint, data, postError)
			arguments(Input)
				app
				endpoint {mustBeNonmissing, mustBeNonempty}
				data struct = []
				postError = true
			end
			opts = weboptions('MediaType', 'application/json', 'ContentType', 'json', 'CharacterEncoding', 'UTF-8');
			apiEndpoint = ['http://' strtok(app.IPAddress.Value)  ':' num2str(app.RemotePort.Value) '/' endpoint];
			try
				response = webwrite(apiEndpoint, data, opts);
				app.postToLog(sprintf('postEndpoint @%s to %s\REPSONSE:\n%s\n', string(datetime()), endpoint, jsonencode(response)));
				if isstruct(response) && isfield(response,'dataType') && matches(response.dataType,'byteStream')
					data = getArrayFromByteStream(uint8(response.data));
					disp(data);
				end
			catch ME
				response = ME.identifier;
				if postError
					app.postToLog(sprintf('postEndpoint "%s" failed - error: %s - %s', endpoint, ME.identifier, ME.message));
				end
			end
		end

		%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
		function response = deleteEndpoint(app, endpoint, postError)
			arguments(Input)
				app
				endpoint {mustBeNonmissing, mustBeNonempty}
				postError = true
			end

			opts = matlab.net.http.HTTPOptions;
			opts.ConnectTimeout = 5;
			opts.ResponseTimeout = 10;
			opts.UseProxy = false;

			uri = matlab.net.URI(['http://' strtok(app.IPAddress.Value)  ':' num2str(app.RemotePort.Value)]);
			uri.Path = string(endpoint);

			request = matlab.net.http.RequestMessage(matlab.net.http.RequestMethod.DELETE);

			try
				response = request.send(uri, opts);
				app.postToLog(sprintf('deleteEndoint to "%s"\nRESPONSE:\n%s\n', endpoint, jsonencode(response)));
			catch ME
				response = ME.identifier;
				if postError
					app.postToLog(sprintf('deleteEndpoint "%s" failed - error: %s - %s', endpoint, ME.identifier, ME.message));
				end
			end
		end

		function status = checkStatus(app)
			status = false;
			try
				app.statusLamp.Color = [0.6 0.6 0.6];
				if isempty(app.status) || isstruct(app.status); app.status = clutil.status; end
				app.status.ip = strtok(app.IPAddress.Value);
				app.status.port = app.RemotePort.Value;
				drawnow limitrate;
				try status = app.status.getStatus; end
				if status == true
					app.statusLamp.Color = [0.2 0.7 0.2];
				end
			catch ME
				app.statusLamp.Color = [0.7 0.3 0.3];
				%fprintf('===>>> Can''t get remote status %s, check cogmoteGO remote runtime...\n', ME.message);
			end
		end

		function [IPs, selected] = getIPs(app)
			items = app.IPAddress.Items;
			for ii = 1:length(items)
				IPs(ii) = strtok(items(ii));
				if matches(app.IPAddress.Value,items{ii}); selected = ii; end
			end
		end
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			addOptickaToPath(); % update path
			app.jzmq = jzmqConnection; % ZMQ connection
			app.alyx = alyxManager; % ALYX connection
			app.status = clutil.status; % Status API

			try
				app.loadPrefs(); % load saved prefs
			end

			if IsLinux && isMATLABReleaseOlderThan("R2026a")
				[~,d]=system('xrandr --screen 0 | grep -E "\\*"');
				if any(contains(d,{'3840','4384','5120','5760','6144'}))
					app.CageLabGUIUIFigure.Position = [20 20 2400 1400];
				end
			end

			if ismac
				mfont = 'Menlo'; pfont = 'Gill Sans';
			elseif ispc
				mfont = 'Consolas'; pfont = 'Calibri';
			else %linux
				mfont = 'Ubuntu Mono'; pfont = 'Ubuntu';
			end
			fl = listfonts;
			if matches('Graublau Sans',fl)
				pfont = 'Graublau Sans';
			elseif matches('Source Sans 3',fl)
				pfont = 'Source Sans 3';
			elseif matches('Source Sans Pro',fl)
				pfont = 'Source Sans Pro';
			end
			if matches('Fira Code',fl)
				mfont = 'Fira Code';
			elseif matches('Cascadia Code',fl)
				mfont = 'Cascadia Code';
			elseif matches('JetBrains Mono',fl)
				mfont = 'JetBrains Mono';
			end
			try set(groot,'defaultAxesFontName',pfont); end
			pl = properties(app);
			for i = 1:length(pl)
				tg = app.(pl{i});
				if isobject(tg) && isprop(tg,'FontName')
					if contains(class(tg),{'DropDown','EditField','TextArea'},'IgnoreCase',true)
						tg.FontName = mfont;
						try if isprop(tg,'FontSize'); tg.FontSize = 12; end; end
					else
						tg.FontName = pfont;
						try if isprop(tg,'FontSize'); tg.FontSize = 12; end; end
					end
				end
			end
			clear pl lf;

			app.Log.Value = {['CageLab-GUI V' clutil.version()]};
			app.CageLabGUIUIFigure.Name = ['CageLab-GUI V' clutil.version()];

			app.IPGlobal.Items = app.IPAddress.Items;
			app.IPGlobal.Value = app.IPAddress.Value;
			app.IPAddressValueChanged();

			app.NonMatch_DMTSValueChanged();

			if exist('~/Code/TBIPmatlab','dir')
				app.TimeBasedProtocolPanel.Enable = "on";
				app.STARTTBIPButton.Enable = "on";
				app.StopButton_TBIP.Enable = "on";
				app.StatusButton_TBIP.Enable ="on";

			end

			if isempty(app.OKAlyxRequest.Items)
				app.OKAlyxRequest.Items = {'sessions?subject=TestSubject', 'weighings?subject=TestSubject'};
				app.OKAlyxRequest.Value = 'sessions?subject=TestSubject';
			end

			app.statusTimer = timer(...
				'ExecutionMode', 'fixedSpacing', ...  % Repeat indefinitely
				'Period', 10, ...                   % 10 second interval
				'BusyMode','drop', ...
				'TimerFcn', @(~,~) app.checkStatus());

			start(app.statusTimer);

		end

		% Button pushed function: StopButton_8, StopButton_DMTS, 
		% ...and 5 other components
		function STOPButton(app, event)
			event.Source.Enable = "off";drawnow;
			status = app.checkStatus();
			if status
				response = questdlg(["CageLab Management:" "STOP Task" "Are You Sure?"], "CageLab-GUI", "Yes");
				if matches(response,'Yes')
					app.sendCmd('exittask',struct('comment','Sending exittask command','timeStamp',GetSecs));
				end
			end
			event.Source.Enable = "on";
			app.checkStatus();
		end

		% Button pushed function: StatusButton, StatusButton_3, 
		% ...and 5 other components
		function STATUSButton(app, event)
			event.Source.Enable = "off";drawnow;
			app.checkStatus();
			getEndpoint(app, 'api/broadcast/data/default/latest');
			event.Source.Enable = "on";
		end

		% Button pushed function: STARTButton_TOUCH
		function STARTTT(app, event)
			opts = [];
			opts.task = 'train';
			opts.command = 'cltasks.startTouchTraining';
			opts.zmq = [];
			opts = generalOptions(app, opts);
			opts.fg = eval(app.TargetDiscColor_TOUCH.Value);

			opts.easyMode = matches(app.Difficulty_TOUCH.Value,'Simple');

			opts.trialTime = app.TrialTime_TOUCH.Value;
			opts.ITI = app.InterTrialTime_TOUCH.Value;

			opts.maxSize = app.InitialTargetSize_TOUCH.Value;
			opts.minSize = app.FinalTargetSize_TOUCH.Value;
			opts.taskType = app.Difficulty_TOUCH.Value;
			opts.stimulus = app.TargetType_TOUCH.Value;
			opts.randomReward = app.RandomRewardTimer_TOUCH.Value;
			opts.randomProbability = app.RandomRewardProbability_TOUCH.Value;
			opts.phase = app.Phase_TOUCH.Value;

			opts.delayDistractors = false;
			opts.initHoldTime = 0.02;
			opts.targetHoldTime = 0.02;
			opts = fixOptions(app, opts);
			checkPasswords(app);
			if matches(app.RemoteControlSwitch.Value, 'On')
				app.sendCmd('run',opts);
			else
				cltasks.startTouchTraining(opts);
			end
		end

		% Button pushed function: STARTMTSButton
		function STARTMTS(app, event)
			opts = [];
			opts.task = 'mts';
			opts.command = 'cltasks.startMatchToSample';
			opts.zmq = [];
			opts = generalOptions(app, opts);
			opts.taskType = lower(app.TaskStage_MTS.Value);
			opts.sampleY = app.SampleYPosition_MTS.Value;
			opts.distractorY = app.DistractorYPosition_MTS.Value;
			opts.object = lower(app.ObjectType_MTS.Value);
			opts.objectSize = app.ObjectSize_MTS.Value;
			opts.objectSep = app.ObjectSeparation_MTS.Value;
			opts.initSize = app.InitiationPointSize_MTS.Value;
			opts.initPosition = eval(app.InitiationPointPositionxy_MTS.Value);
			opts.fg = app.InitiationPointColour_MTS.Value;
			opts.trialTime = app.TrialTime_MTS.Value;
			opts.distractorN = app.Numberofdistractors_MTS.Value;
			opts.delayTime = 0;
			opts.delayDistractors = false;
			opts.initHoldTime = app.InitiationHoldTime_MTS.Value;
			opts.targetHoldTime = app.TargetHoldTime_MTS.Value;
			opts = app.fixOptions(opts);
			checkPasswords(app);
			if matches(app.RemoteControlSwitch.Value, 'On')
				app.sendCmd('run',opts);
			else
				cltasks.startMatchToSample(opts);
			end
		end

		% Button pushed function: STARTDMTSButton
		function STARTDMTS(app, event)
			opts = [];
			if matches(app.NonMatch_DMTS.Value,'On')
				opts.task = 'dnts';
				opts.nonmatch = true;
			else
				opts.task = 'dmts';
				opts.nonmatch = false;
			end
			opts.command = 'cltasks.startMatchToSample';
			opts.zmq = [];
			opts = generalOptions(app, opts);

			opts.initSize = app.InitSize_DMTS.Value;
			opts.initPosition = eval(app.InitPosition_DMTS.Value);
			opts.initHoldTime = app.InitHoldTime_DMTS.Value;
			opts.initColour = str2num(app.InitColour_DMTS.Value);

			opts.taskType = lower(app.TaskStage_DMTS.Value);
			opts.sampleY = app.SampleYPosition_DMTS.Value;
			opts.distractorY = app.DistractorYPosition_DMTS.Value;
			opts.object = lower(app.ObjectType_DMTS.Value);
			opts.objectSize = app.ObjectSize_DMTS.Value;
			opts.objectSep = app.ObjectSeparation_DMTS.Value;
			
			opts.fg = app.InitColour_DMTS.Value;
			opts.trialTime = app.TrialTime_DMTS.Value;
			opts.distractorN = app.Numberofdistractors_DMTS.Value;
			opts.delayTime = eval(app.DelayTime_DMTS.Value);
			opts.sampleTime = eval(app.SampleDisplayTime_DMTS.Value);
			opts.targetHoldTime = app.TargetHoldTime_DMTS.Value;
			opts.delayDistractors = app.DelayDistractors_DMTS.Value;

			opts = app.fixOptions(opts);
			checkPasswords(app);
			if matches(app.RemoteControlSwitch.Value, 'On')
				app.sendCmd('run',opts);
			else
				cltasks.startMatchToSample(opts);
			end
		end

		% Button pushed function: STARTTHINGSButton
		function STARTOOO(app, event)
			opts = [];
			opts.task = 'things';
			opts.command = 'cltasks.startThings';
			opts.zmq = [];
			opts = generalOptions(app, opts);
			opts.folderThings =  app.folder_OOO.Value;
			opts.taskType = lower(app.TaskStage_OOO.Value);
			opts.trainingSet = lower(app.ObjectType_OOO.Value);
			opts.useStaircase = app.UseStaircase_OOO.Value;
			opts.easyMode = app.easyMode_OOO.Value;
			opts.simplifiedImages = app.simplified_OOO.Value;
			opts.phase = app.Phase_OOO.Value;

			opts.objectSize = app.ObjectSize_OOO.Value;
			opts.objectSep = app.ObjectSeparation_OOO.Value;
			opts.distractorSpreadAngle = app.DistractorAngleSpread_OOO.Value;
			opts.distractorCenterAngle = app.DistractorCenterAngle_OOO.Value;

			opts.distractorOpacity = app.DistractorOpacity_OOO.Value;
			opts.pedestalOpacity = app.PedestalOpacity_OOO.Value;
			opts.object = lower(app.ObjectType_OOO.Value);
			opts.initSize = app.InitiationPointSize_OOO.Value;
			opts.initPosition = eval(app.InitiationPointPositionxy_OOO.Value);
			opts.fg = app.InitiationPointColour_OOO.Value;
			opts.trialTime = app.TrialTime_OOO.Value;
			opts.initHoldTime = app.InitiationHoldTime_OOO.Value;
			opts.targetHoldTime = app.TargetHoldTime_OOO.Value;
			opts.equalizeN =  app.equalizeN_OOO.Value;
			if opts.equalizeN > 0
				opts.equalizeImages = true;
			else
				opts.equalizeImages = false;
			end
			opts = app.fixOptions(opts);
			checkPasswords(app);
			if matches(app.RemoteControlSwitch.Value, 'On')
				app.sendCmd('run',opts);
			else
				cltasks.startThings(opts);
			end
		end

		% Button pushed function: STARTPMTButton
		function STARTPMT(app, event)
			opts = [];
			opts.task = 'pacman';
			opts.command = 'PacMan_CageLab';
			opts.zmq = [];
			opts = generalOptions(app, opts);
			opts.audioBeeps = app.RewardBeeps_PMT.Value;
			opts.forceResolution = app.ForceResolution_PMT.Value;
			opts.joystickDelay = app.JoyStickDelay_PMT.Value;
			opts.mapPath = app.MapsPath_PMT.Value;
			opts.mapPath = replace(opts.mapPath,getenv("HOME"),"~");
			[~,opts.mapName] = fileparts(app.Map_PMT.Value);
			opts = fixOptions(app, opts);
			checkPasswords(app);
			if matches(app.RemoteControlSwitch.Value, 'On')
				app.sendCmd('run',opts);
			else
				PacMan_CageLab(opts);
			end
		end

		% Button pushed function: STARTIEDButton
		function STARTIED(app, event)
			opts = [];
			opts.task = 'ied';
			opts.command = 'cltasks.startIEDmorphobes';
			opts.zmq = [];
			opts = generalOptions(app, opts);
			opts.object = lower(app.ObjectType_IED.Value);

			%opts.sampleY = app.SampleYPosition_DMTS.Value;
			%opts.distractorY = app.DistractorYPosition_DMTS.Value;
			%opts.objectSize = app.ObjectSize_DMTS.Value;
			%opts.objectSep = app.ObjectSeparation_DMTS.Value;

			opts.objectSize = app.ObjectSize_IED.Value;
			opts.objectSep = app.ObjectSeparation_IED.Value;
			opts.distractorSpreadAngle = app.DistractorAngleSpread_IED.Value;
			opts.distractorCenterAngle = app.DistractorCenterAngle_IED.Value;

			opts.initSize = app.InitSize_IED.Value;
			opts.initPosition = eval(app.InitPosition_IED.Value);
			opts.initHoldTime = app.InitHoldTime_IED.Value;
			opts.initColour = str2num(app.InitColour_IED.Value);

			opts.taskType = lower(app.TaskType_IED.Value);
			opts.numTargets = str2num(app.Targets_IED.Value);
			opts.criterion = app.criterion_IED.Value;
			opts.maxIncorrect = app.MaxIncorrect_IED.Value;
			opts.idDimension = lower(app.ID_IED.Value);
			opts.edDimension = lower(app.ED_IED.Value);
			opts.distractors = logical(app.distractors_IED.Value);
			opts.randomiseDistractors = logical(app.randomiseDistractors_IED.Value);
			opts.distractorOne = app.distractorOne_IED.Value;
			opts.distractorTwo = app.distractorTwo_IED.Value;
			opts.useExemplars = true;

			%   in.distractors = true;    % show the two non-ID/ED dimensions; false holds
			%                             %   them neutral (0). Default: false for 2 targets,
			%                             %   true for 4 targets.
			%   in.randomiseDistractors = true; % draw distractor values from the dataset
			%                             %   levels each trial; false uses the fixed values
			%                             %   below. Ignored when distractors=false.
			%   in.distractorOne = 0;     % fixed value for the first non-ID/ED dimension
			%   in.distractorTwo = 0;     % fixed value for the second non-ID/ED dimension
			%   in.useExemplars = true;   % draw a fresh exemplar from the dataset eac

			opts = app.fixOptions(opts);
			checkPasswords(app);
			if matches(app.RemoteControlSwitch.Value, 'On')
				app.sendCmd('run',opts);
			else
				cltasks.startIEDmorphobes(opts);
			end
		end

		% Button pushed function: STARTDCATButton
		function STARTDCATB(app, event)
			opts = [];
			opts.task = 'categorisation';
			opts.command = 'cltasks.startDragCategorisation';
			opts.zmq = [];
			opts = generalOptions(app, opts);
			opts.fg = eval(app.InitiationPointColourEditField.Value);
			opts.taskType = app.TaskStageDropDown_3.Value;
			opts.trialTime = app.TrialTimeEditField_7.Value;
			opts.initPosition = eval(app.InitiationPointPositionxyEditField.Value);
			opts.target1Pos = eval(app.TargetPosition1EditField.Value);
			opts.target2Pos = eval(app.TargetPosition2EditField.Value);
			opts.initSize = app.InitiationPointSizeEditField.Value;
			opts.targetSize = app.ObjectSizeEditField_2.Value;
			opts = fixOptions(app, opts);
			checkPasswords(app);
			if matches(app.RemoteControlSwitch.Value, 'On')
				app.sendCmd('run',opts);
			else
				cltasks.startDragCategorisation(opts);
			end
		end

		% Button pushed function: ChangeResourceFolderButton
		function FolderSelection(app, event)
			val = uigetdir;
			if ischar(val)
				app.folderEditField.Value = val;
			end
		end

		% Close request function: CageLabGUIUIFigure
		function CageLabGUIUIFigureCloseRequest(app, event)
			try app.savePrefs(); end %#ok<*TRYNC>
			if isvalid(app.statusTimer)
				stop(app.statusTimer);
				delete(app.statusTimer);
			end
			delete(app);
		end

		% Callback function
		function StartConductorButtonPushed(app, event)
			r = getEndpoint(app,['exps/' app.conductorID], false);
			if isempty(r) || ischar(r)
				data = struct('nickname','conductor','type','git','address',...
					'','exec','matlab -nodesktop -nosplash -r "c = theConductor; run(c)"');
				r = postEndpoint(app, 'exps', data);
			end
			if isstruct(r) && isfield(r,'id')
				app.conductorID = r.id;
				r = getEndpoint(app,['exps/' app.conductorID], false);
				if isstruct(r)
					try
						r = postEndpoint(app,['exps/' app.conductorID '/start']);
					catch ME
						postToLog(app,'Couldn''t start experiment...');
						getReport(ME)
					end
				end
			else
				postToLog(app,'Couldn''t identify the correct exps endpoint');
			end
		end

		% Callback function
		function StopConductorButtonPushed(app, event)
			r = getEndpoint(app,['exps/' app.conductorID], false);
			if isstruct(r) && isfield(r,'id')
				r = deleteEndpoint(app, ['exps/' app.conductorID], false);
			end
		end

		% Button pushed function: PingButton, PingButton_MAIN
		function PingButtonPushed(app, event)
			event.Source.Enable = "off"; drawnow;
			try
				if IsWin
					[~,msg] = system(['ping -w 1000 -n 2 ' strtok(app.IPAddress.Value)]);
				else
					[~,msg] = system(['ping -W 1 -c 2 ' strtok(app.IPAddress.Value)]);
				end
				postToLog(app, string(msg));
			end
			event.Source.Enable = "on";
		end

		% Value changed function: RemoteControlSwitch
		function RemoteControlSwitchValueChanged(app, event)
			value = app.RemoteControlSwitch.Value;
			if matches(value,"Off")
				app.StopButton_MAIN.Enable = "off";
				app.StopButton_TOUCH.Enable = "off";
				app.StopButton_MTS.Enable = "off";
				app.StopButton_DMTS.Enable = "off";
				app.StopButton_OOO.Enable = "off";
				app.RunCommandButton.Enable = "off";
				app.RemoteCommand.Enable = "off";
				app.CustomCommand.Enable = "off";
				app.ButtonsGrid.Visible = "off";
			else
				app.StopButton_MAIN.Enable = "on";
				app.StopButton_TOUCH.Enable = "on";
				app.StopButton_MTS.Enable = "on";
				app.StopButton_DMTS.Enable = "on";
				app.StopButton_OOO.Enable = "on";
				app.RunCommandButton.Enable = "on";
				app.RemoteCommand.Enable = "on";
				app.CustomCommand.Enable = "on";
				app.ButtonsGrid.Visible = "on";
			end
		end

		% Button pushed function: AddIP
		function AddIPPushed(app, event)
			vals = menuN('CageLab-GUI Add IP Address',{'t|','IP Address:';'t|','Computer Name (optional):'});
			if iscell(vals) && ~isempty(vals{1})
				it = app.IPAddress.Items;
				it = [it [vals{1} '  ' vals{2}]];
				app.IPAddress.Items = it;
			end
		end

		% Button pushed function: RmIP
		function RmIPButtonPushed(app, event)
			it = app.IPAddress.Items;
			val = app.IPAddress.Value;
			if matches(val,'127.0.0.1'); return; end
			idx = matches(it,val);
			it(idx) = [];
			app.IPAddress.Items = it;
		end

		% Button pushed function: OKAlyxLOGINButton
		function AlyxLOGINButtonPushed(app, event)
			app.CageLabGUIUIFigure.Pointer = 'watch';
			app.OKAlyxLOGINButton.Text = '.';drawnow;

			alyx = app.alyx;
			if ~isa(alyx,'alyxManager')
				alyx = alyxManager;
			end
			alyx.user = app.alyxUser.Value;
			alyx.baseURL = app.alyxIP.Value;

			% this checks for AWS_ID, AWS_KEY and AlyxPassword secrets
			% stored locally
			setSecrets(alyx);

			login(alyx);
			WaitSecs(0.1);

			if alyx.loggedIn
				app.OKAlyxLOGINButton.FontColor = [0 0.6 0];
				app.OKAlyxLOGINButton.Text = '..procedures';drawnow;
				try
					procedures = alyx.getData('procedures');
					procedures = {procedures(:).name};
					app.OKAlyxProcedure.Items = procedures;
					idx = find(contains(procedures,'Behavior training'));
					if ~isempty(idx); app.OKAlyxProcedure.ValueIndex = idx(1); end
				end
				app.OKAlyxLOGINButton.Text = '...projects';drawnow;
				try
					projects = alyx.getData('projects');
					projects = {projects(:).name};
					app.OKAlyxProject.Items = projects;
				end
				app.OKAlyxLOGINButton.Text = '....brain-regions';drawnow;
				if length(app.OKAlyxBrainRegion.Items) < 10
					try
						regions = alyx.getData('brain-regions');
						regions = {regions(:).name};
						app.OKAlyxBrainRegion.Items = regions;
						idx = find(contains(regions,'Cerebral cortex'));
						if ~isempty(idx); app.OKAlyxBrainRegion.ValueIndex = idx(1); end
					end
				end
				app.OKAlyxLOGINButton.BackgroundColor = [0.8 1 0.8];
				app.OKAlyxLOGINButton.Text = 'LOGGED IN ✔';
				app.OKAlyxRequestButton.Enable = "on";
				app.OKAlyxRequestButton.BackgroundColor = [0.8 1 0.8];
				app.AlyxAddWeight.Enable = "on";
				app.AlyxAddWater.Enable = "on";
				app.SubjectWeight.Enable = "on";
				app.SubjectWater.Enable = "on";
			else
				warning('Couldn''t login to Alyx, please check network');
				app.OKAlyxLOGINButton.FontColor = [0 0 0];
				app.OKAlyxLOGINButton.BackgroundColor = [0.96 0.96 0.96];
				app.OKAlyxLOGINButton.Text = 'LOGIN';
				app.OKAlyxRequestButton.Enable = "off";
				app.OKAlyxRequestButton.BackgroundColor = [0.96 0.96 0.96];
				app.AlyxAddWeight.Enable = "off";
				app.AlyxAddWater.Enable = "off";
				app.SubjectWeight.Enable = "off";
				app.SubjectWater.Enable = "off";
			end
			app.CageLabGUIUIFigure.Pointer = 'arrow';
		end

		% Button pushed function: OKAlyxLOGOUTButton
		function AlyxLOGOUTButtonPushed(app, event)
			if isa(app.alyx,'alyxManager')
				logout(app.alyx);
				app.OKAlyxLOGINButton.FontColor = [0 0 0];
				app.OKAlyxLOGINButton.BackgroundColor = [0.96 0.96 0.96];
				app.OKAlyxLOGINButton.Text = 'LOGIN';
				app.OKAlyxRequestButton.Enable = "off";
				app.OKAlyxRequestButton.BackgroundColor = [0.96 0.96 0.96];
				app.AlyxAddWeight.Enable = "off";
				app.AlyxAddWater.Enable = "off";
				app.SubjectWeight.Enable = "off";
				app.SubjectWater.Enable = "off";
				app.CageLabGUIUIFigure.Pointer = 'arrow';
			end
		end

		% Button pushed function: OKAlyxRequestButton
		function AlyxRequestButtonPushed(app, event)
			alyx = app.alyx;
			if isa(alyx,'alyxManager') && alyx.loggedIn
				app.CageLabGUIUIFigure.Pointer = 'watch';
				ic = app.OKAlyxRequestButton.Icon;
				try app.OKAlyxRequestButton.Icon = ''; end
				app.OKAlyxRequestButton.FontColor = [0.6 0.6 0.6];
				app.OKAlyxRequestButton.BackgroundColor = [0.96 0.96 0.96];
				app.OKAlyxRequestButton.Text = '...';drawnow;
				request = app.OKAlyxRequest.Value;
				try
					[data, s] = alyx.getData(request);
					if s == 200 && ~isempty(data) && isstruct(data)
						showAlyxTable(app, data)
					else
						warning('Alyx request returned no data!');
						postToLog(app, 'Alyx request returned no data!')
					end
				end
				WaitSecs('YieldSecs',0.25);
				app.OKAlyxRequestButton.FontColor = [0 0 0];
				app.OKAlyxRequestButton.BackgroundColor = [0.8 1 0.8];
				try app.OKAlyxRequestButton.Icon = ic; end
				app.OKAlyxRequestButton.Text = '';
				app.CageLabGUIUIFigure.Pointer = 'arrow';
			end
		end

		% Button pushed function: AlyxAddWeight
		function AlyxAddWeightPushed(app, event)
			if isa(app.alyx,'alyxManager') && app.alyx.loggedIn
				app.CageLabGUIUIFigure.Pointer = 'watch';
				data = [];
				data.weight = str2num(app.SubjectWeight.Value)*1e3;
				data.subject = app.OKSubject.Value;
				data.user = app.alyxUser.Value;
				%[data, statusCode] = postData(me, endpoint, data, requestMethod)
				try
					[ret,st] = app.alyx.postData('weighings',data,'POST');
					postToLog(app,ret,'Result to add new weight:');
				catch ME
					postToLog(app,sprintf('Couldn''t post to database: %s %s', ME.message,ME.identifier));
				end
				try
					[retw, s] = app.alyx.getData(['weighings?subject=' data.subject]);
					if s == 200 && ~isempty(retw) && isstruct(retw)
						showAlyxTable(app, retw)
					else
						warning('Alyx request returned no data!');
						postToLog(app, 'Alyx request returned no data!','Get Weights Error')
					end
				end
				app.CageLabGUIUIFigure.Pointer = 'arrow';
			end
		end

		% Button down function: PacmanTask
		function PacmanTaskButtonDown(app, event)
			dd = [];
			try
				if ~isempty(app.MapsPath_PMT.Value) && exist(app.MapsPath_PMT.Value,'dir')
					dd = app.MapsPath_PMT.Value;
				else
					self = which('PacMan_CageLab');
					dd = fileparts(self);
					dd = [dd filesep 'Maps'];
					app.MapsPath_PMT.Value = dd;
				end
				dd = dir(dd);
				if ~isempty(dd)
					af = @(in) contains(in.name,'Generate');
					idx = arrayfun(af,dd);
					maps = {dd(idx).name};
					app.Map_PMT.Items = maps;
				end
			catch ME
				getReport(ME);
				app.Map_PMT.Items = {''};
				warndlg('Could not get Maps...');
			end
		end

		% Button pushed function: HideDesktopButton
		function HideDesktopButtonPushed(app, event)
			[r,d] = sendCmd(app, 'hidedesktop', struct('color',eval(app.HideScreenColorEditField.Value)));
			disp(r);
			postToLog(app,d,"Hide Desktop Response:");
		end

		% Button pushed function: ShowDesktopButton
		function ShowDesktopButtonPushed(app, event)
			[r,d] = sendCmd(app, 'showdesktop', []);
			disp(r);
			postToLog(app,d,"Show Desktop Response:");
		end

		% Button pushed function: PoweroffButton
		function PoweroffButtonPushed(app, event)
			response = questdlg(["CageLab Management:" "Power OFF CageLab!?!" "Are you sure?"],"CageLab-GUI", "No");
			if matches(response,'Yes')
				[r,d] = sendCmd(app, 'run', struct('command','!poweroff','args','none'));
				disp(r);
				postToLog(app,d,"Power Off Response:");
			end
		end

		% Button pushed function: PoweroffALLButton
		function PoweroffALLButtonPushed(app, event)
			response = questdlg(["CageLab Management:" "Power OFF ALL CageLab!?!" "Are you sure?"],"CageLab-GUI", "No");
			if matches(response,'Yes')
				[IPs, sel] = getIPs(app);
				for jj = 1:length(IPs)
					if contains('127.0.0.1',IPs{jj}); continue; end
					app.IPAddress.ValueIndex = jj;
					[r,d] = sendCmd(app, 'run', struct('command','!poweroff','args','none'));
					disp(r);
					postToLog(app,d,sprintf("Power Off %s Response:",IPs{jj}));
				end
				app.IPAddress.ValueIndex = sel;
			end
		end

		% Button pushed function: ResetMATLABButton
		function ResetMATLABButtonPushed(app, event)
			response = questdlg(["CageLab Management:" "Restart Remote MATLAB?"],"CageLab-GUI", "Yes");
			if matches(response,'Yes')
				[r,d] = sendCmd(app, 'run', struct('command','!zsh ~/Code/CageLab/software/scripts/cagelab-reset.sh','args','none'));
				disp(r);
				postToLog(app,d,"Response:");
			end
		end

		% Button pushed function: ResetOBSButton
		function ResetOBSButtonPushed(app, event)
			response = questdlg(["CageLab Management:" "Restart Streaming?"],"CageLab-GUI", "Yes");
			if matches(response,'Yes')
				[r,d] = sendCmd(app, 'run', struct('command','!zsh ~/Code/CageLab/software/scripts/cagelab-reset-OBS.sh','args','none'));
				disp(r);
				postToLog(app,d,"Response:");
			end
		end

		% Button pushed function: DisableSleepButton
		function DisableSleepButtonPushed(app, event)
			response = questdlg(["CageLab Management:" "Disable Screen Sleep?"],"CageLab-GUI", "Yes");
			if matches(response,'Yes')
				[r,d] = sendCmd(app, 'run', struct('command','!xset -dpms s off; xdotool key shift','args','none'));
				disp(r);
				postToLog(app,d,"Response:");
			end
		end

		% Button pushed function: EnableSleepButton
		function EnableSleepButtonPushed(app, event)
			response = questdlg(["CageLab Management:" "Enable Screen Sleep?"],"CageLab-GUI", "Yes");
			if matches(response,'Yes')
				[r,d] = sendCmd(app, 'run', struct('command','!xset s 300 dpms 600 0 0','args','none'));
				disp(r);
				postToLog(app,d,"Response:");
			end
		end

		% Button pushed function: ChangeButton
		function ChangeButtonPushed(app, event)
			value = upper(app.PasswordReset.Value);
			if contains(value,'ALYX')
				setSecret('AlyxPassword','Overwrite',true);
			elseif contains(value,'ID')
				setSecret('AWS_ID','Overwrite',true);
			elseif contains(value,'KEY')
				setSecret('AWS_KEY','Overwrite',true);
			end
		end

		% Button pushed function: TouchTestButton
		function TouchTestButtonPushed(app, event)
			response = questdlg(["CageLab Management:" "Test Touch Screen?"],"CageLab-GUI", "Yes");
			if matches(response,'Yes')
				id = num2str(app.TouchDeviceIDEditField.Value);
				[r,d] = sendCmd(app, 'run', struct('command', "clutil.touchTest([],[],true)",'args','none'));
				disp(r);
				postToLog(app,d,"Response:");
			end
		end

		% Button pushed function: DisableTouchButton
		function DisableTouchButtonPushed(app, event)
			response = questdlg(["CageLab Management:" "Disabe Touch Screen?"],"CageLab-GUI", "Yes");
			if ~matches(response,'Yes'); return; end
			cmd = ['touchManager.enableTouchDevice(''' app.TouchDeviceNameEditField.Value ''', false);'];
			if ~isempty(cmd)
				[r,d] = sendCmd(app, 'run', struct('command',cmd,'args','none'));
				disp(r);
				postToLog(app,d,"Response data:");
			end
		end

		% Button pushed function: EnableTouchButton
		function EnableTouchButtonPushed(app, event)
			response = questdlg(["CageLab Management:" "Enable Touch Screen?"],"CageLab-GUI", "Yes");
			if ~matches(response,'Yes'); return; end
			cmd = ['touchManager.enableTouchDevice(''' app.TouchDeviceNameEditField.Value ''', true);'];
			if ~isempty(cmd)
				[r,d] = sendCmd(app, 'run', struct('command',cmd,'args','none'));
				disp(r);
				postToLog(app,d,"Response data:");
			end
		end

		% Button pushed function: RunCommandButton
		function RunCommandButtonPushed(app, event)
			event.Source.Enable = "off";
			app.RemoteCommand.Enable = "off"; drawnow;
			cmd = app.RemoteCommand.ValueIndex;
			try
				switch cmd
					case 1
						r = getEndpoint(app, 'api/alive');
					case 2
						r = getEndpoint(app, 'api/device');
					case 3 % get time
						[r,d] = sendCmd(app, 'gettime', struct('currentTime',datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss.SSS'),'GetSecs',GetSecs));
						disp(r);
						if isstruct(d)
							d.roundTrip = GetSecs - d.clientGetSecs;
						end
						postToLog(app,d,"Response data:");
					case 4 % custom command
						cmd = app.CustomCommand.Value;
						if ~isempty(cmd)
							response = questdlg(["CageLab Management:" "Running Custom Command" "Are You Sure?"],"CageLab-GUI", "Yes");
							if matches(response,'Yes')
								[r,d] = sendCmd(app, 'run', struct('command',cmd,'args','none'));
								disp(r);
								postToLog(app,d,"Response data:");
							end
						end
					case 5 %get last run
						[r,d] = sendCmd(app, 'getlastrun', []);
						disp(r);
						if isstruct(r) && isfield(r,'command') && matches(r.command,'taskdata')
							postToLog(app,"Will plot the remote data...");
							try
								assignin("base","cagelabData",d);
								d.plotData();
							catch ME
								postToLog(app,"Couldn't plot data...");
							end
						else
							postToLog(app,d);
						end
					case 6 % audio test
						postToLog(app,"You MUST exit sound demo on remote device holding ESC for around 1 seconds");
						response = questdlg(["CageLab Management:" "You MUST exit sound demo on remote device holding ESC for around 1 seconds" "Are You Sure?"],"CageLab-GUI", "Yes");
						if matches(response,'Yes')
							cmd = ['BasicSoundOutputDemo(1, [], ' app.AudioDeviceEditField.Value ')'];
							postToLog(app, ['Run: ' cmd]);
							[r,d] = sendCmd(app, 'run', struct('command',cmd,'args','none'));
							disp(r);
							postToLog(app,d,"Response:");
						end
					case 7 % PTB test
						postToLog(app,'Run: VBLSyncTest');
						[r,d] = sendCmd(app, 'run', struct('command','VBLSyncTest','args','none'));
						disp(r);
						postToLog(app,d,"Response data:");
					case 8 % exit server
						[r,d] = sendCmd(app, 'exit', []);
						disp(r);
						postToLog(app,d);
					case 9
						[r,d] = sendCmd(app, 'echo', {'test','data'});
						disp(r);
						postToLog(app,d);
					otherwise

				end
			end
			event.Source.Enable = "on";
			app.RemoteCommand.Enable = "on";
		end

		% Value changed function: IPAddress
		function IPAddressValueChanged(app, event)
			app.IPGlobal.Items = app.IPAddress.Items;
			app.IPGlobal.Value = app.IPAddress.Value;
			name = "";
			try
				nameMap = getpref('cagelabnames', 'nameMap');
				if ~isstruct(nameMap); return; end
				ip = split(string(app.IPAddress.Value)," ");
				ip = regexprep(ip(1),"\.","_");
				ip = "IP" + ip;
				if isfield(nameMap,ip)
					name = string(nameMap.(ip));
				end
				if ~ismissing(name) && strlength(name) > 0
					app.subjectNameGlobal.Value = name;
					app.OKSubject.Value = name;
				end
			catch ME
				getReport(ME);
			end
			app.checkStatus();
		end

		% Value changed function: IPGlobal
		function IPGlobalValueChanged(app, event)
			app.IPAddress.Items = app.IPGlobal.Items;
			app.IPAddress.Value = app.IPGlobal.Value;
			app.IPAddressValueChanged([]);
		end

		% Value changed function: OKSubject
		function OKSubjectValueChanged(app, event)
			app.subjectNameGlobal.Value = app.OKSubject.Value;
			name = app.OKSubject.Value;
			ip = split(string(app.IPAddress.Value)," ");
			ip = regexprep(ip(1),"\.","_");
			ip = "IP" + ip;
			try nameMap = getpref('cagelabnames', 'nameMap'); end
			if ~exist('nameMap','var') || ~isstruct(nameMap)
				nameMap = struct;
			end
			nameMap.(ip) = string(name);
			try setpref('cagelabnames','nameMap',nameMap); end
		end

		% Value changed function: subjectNameGlobal
		function subjectNameGlobalValueChanged(app, event)
			app.OKSubject.Value = app.subjectNameGlobal.Value;
			app.OKSubjectValueChanged([]);
		end

		% Value changed function: NonMatch_DMTS
		function NonMatch_DMTSValueChanged(app, event)
			value = app.NonMatch_DMTS.Value;
			if matches(value,'On')
				app.STARTDMTSButton.Text = "Start DNTS";
			else
				app.STARTDMTSButton.Text = "Start DMTS";
			end
		end

		% Button pushed function: GetPath_PMT
		function GetPath_PMTPushed(app, event)
			val = uigetdir;
			if ischar(val)
				app.MapsPath_PMT.Value = val;
			end
			PacmanTaskButtonDown(app);
		end

		% Button pushed function: alyxGUIOpen
		function alyxGUIOpenButtonPushed(app, event)
			alyxGUI();
		end

		% Button pushed function: UserGuideButton
		function UserGuideButtonPushed(app, event)
			if IsLinux
				p = fileparts(which(mfilename));
				cmd = ['xdg-open ' fullfile(p, 'docs', 'UserGuide.pdf')];
				system(cmd);
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			% Get the file path for locating images
			pathToMLAPP = fileparts(mfilename('fullpath'));
			app.CageLabGUIUIFigure = uifigure('Visible', 'off');
			app.CageLabGUIUIFigure.Name = 'CageLab-GUI';
			app.CageLabGUIUIFigure.Icon = fullfile(pathToMLAPP, 'ui', 'cagelab.png');
			app.CageLabGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @CageLabGUIUIFigureCloseRequest, true);
			app.GridLayoutMAIN = uigridlayout(app.CageLabGUIUIFigure);
			app.TabGroup = uitabgroup(app.GridLayoutMAIN);
			app.MainTab = uitab(app.TabGroup);
			app.MainTab.Title = '⚙️ Main';
			app.GridLayout = uigridlayout(app.MainTab);
			app.GlobalSettingsPanel = uipanel(app.GridLayout);
			app.GlobalSettingsPanel.Title = '🌏 Global Settings';
			app.GridLayout2 = uigridlayout(app.GlobalSettingsPanel);
			app.CageLabRemoteControlPanel = uipanel(app.GridLayout2);
			app.CageLabRemoteControlPanel.Title = '📡 CageLab Remote Control';
			app.GridLayout4 = uigridlayout(app.CageLabRemoteControlPanel);
			app.RemotePort = uieditfield(app.GridLayout4, 'numeric');
			app.RemotePort.Value = 9012;
			app.RemoteControlSwitchLabel = uilabel(app.GridLayout4);
			app.RemoteControlSwitchLabel.Text = '🎛️ Remote Control';
			app.RemoteControlSwitch = uiswitch(app.GridLayout4, 'slider');
			app.RemoteControlSwitch.ValueChangedFcn = createCallbackFcn(app, @RemoteControlSwitchValueChanged, true);
			app.RemoteControlSwitch.Value = 'On';
			app.PingButton = uibutton(app.GridLayout4, 'push');
			app.PingButton.ButtonPushedFcn = createCallbackFcn(app, @PingButtonPushed, true);
			app.PingButton.Icon = fullfile(pathToMLAPP, 'ui', 'pin.svg');
			app.PingButton.Tooltip = {'Ping Remote Device'};
			app.PingButton.Text = '';
			app.RunCommandButton = uibutton(app.GridLayout4, 'push');
			app.RunCommandButton.ButtonPushedFcn = createCallbackFcn(app, @RunCommandButtonPushed, true);
			app.RunCommandButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.RunCommandButton.Text = 'Run Command ⤵';
			app.RemoteCommand = uilistbox(app.GridLayout4);
			app.RemoteCommand.Items = {'cogmoteGO: Check if cogmoteGO is Alive?', 'cogmoteGO: Get Remote Device Info', 'Conductor: Test Roundtrip Time Sync', 'Conductor: RUN Custom Command', 'Conductor: Retieve & Plot Data Ongoing/Last Session', 'Conductor: Audio Test', 'Conductor: PTB VBL Test', 'Conductor: Exit Conductor Server Loop'};
			app.RemoteCommand.Value = 'cogmoteGO: Check if cogmoteGO is Alive?';
			app.IPAddress = uidropdown(app.GridLayout4);
			app.IPAddress.Items = {'127.0.0.1 localhost'};
			app.IPAddress.ValueChangedFcn = createCallbackFcn(app, @IPAddressValueChanged, true);
			app.IPAddress.Tooltip = {'The remote system IP address'};
			app.IPAddress.Value = '127.0.0.1 localhost';
			app.RemoteServerPortEditFieldLabel_2 = uilabel(app.GridLayout4);
			app.RemoteServerPortEditFieldLabel_2.Text = 'Remote Device Address';
			app.AddIP = uibutton(app.GridLayout4, 'push');
			app.AddIP.ButtonPushedFcn = createCallbackFcn(app, @AddIPPushed, true);
			app.AddIP.Icon = fullfile(pathToMLAPP, 'ui', 'plus.svg');
			app.AddIP.Tooltip = {'Add'};
			app.AddIP.Text = '';
			app.RmIP = uibutton(app.GridLayout4, 'push');
			app.RmIP.ButtonPushedFcn = createCallbackFcn(app, @RmIPButtonPushed, true);
			app.RmIP.Icon = fullfile(pathToMLAPP, 'ui', 'minus.svg');
			app.RmIP.Tooltip = {'Remove'};
			app.RmIP.Text = '';
			app.CustomCommand = uieditfield(app.GridLayout4, 'text');
			app.CustomCommand.Placeholder = 'custom command';
			app.AlyxLink = uihyperlink(app.GridLayout4);
			app.AlyxLink.Enable = 'off';
			app.AlyxLink.Text = '...Last Session URL...';
			app.ButtonsGrid = uigridlayout(app.GridLayout4);
			app.HideDesktopButton = uibutton(app.ButtonsGrid, 'push');
			app.HideDesktopButton.ButtonPushedFcn = createCallbackFcn(app, @HideDesktopButtonPushed, true);
			app.HideDesktopButton.Text = 'Hide Desktop';
			app.ResetMATLABButton = uibutton(app.ButtonsGrid, 'push');
			app.ResetMATLABButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMATLABButtonPushed, true);
			app.ResetMATLABButton.Text = 'Reset-MATLAB';
			app.ResetOBSButton = uibutton(app.ButtonsGrid, 'push');
			app.ResetOBSButton.ButtonPushedFcn = createCallbackFcn(app, @ResetOBSButtonPushed, true);
			app.ResetOBSButton.Text = 'Reset-OBS';
			app.PoweroffButton = uibutton(app.ButtonsGrid, 'push');
			app.PoweroffButton.ButtonPushedFcn = createCallbackFcn(app, @PoweroffButtonPushed, true);
			app.PoweroffButton.Text = 'Poweroff';
			app.ShowDesktopButton = uibutton(app.ButtonsGrid, 'push');
			app.ShowDesktopButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDesktopButtonPushed, true);
			app.ShowDesktopButton.Text = 'Show Desktop';
			app.DisableSleepButton = uibutton(app.ButtonsGrid, 'push');
			app.DisableSleepButton.ButtonPushedFcn = createCallbackFcn(app, @DisableSleepButtonPushed, true);
			app.DisableSleepButton.Text = 'Disable Sleep';
			app.EnableSleepButton = uibutton(app.ButtonsGrid, 'push');
			app.EnableSleepButton.ButtonPushedFcn = createCallbackFcn(app, @EnableSleepButtonPushed, true);
			app.EnableSleepButton.Text = 'Enable Sleep';
			app.TouchTestButton = uibutton(app.ButtonsGrid, 'push');
			app.TouchTestButton.ButtonPushedFcn = createCallbackFcn(app, @TouchTestButtonPushed, true);
			app.TouchTestButton.Text = 'Touch Test';
			app.EnableTouchButton = uibutton(app.ButtonsGrid, 'push');
			app.EnableTouchButton.ButtonPushedFcn = createCallbackFcn(app, @EnableTouchButtonPushed, true);
			app.EnableTouchButton.Text = 'Enable Touch';
			app.DisableTouchButton = uibutton(app.ButtonsGrid, 'push');
			app.DisableTouchButton.ButtonPushedFcn = createCallbackFcn(app, @DisableTouchButtonPushed, true);
			app.DisableTouchButton.Text = 'Disable Touch';
			app.WakeUpALLButton = uibutton(app.ButtonsGrid, 'push');
			app.WakeUpALLButton.Enable = 'off';
			app.WakeUpALLButton.Text = 'Wake Up ALL';
			app.PoweroffALLButton = uibutton(app.ButtonsGrid, 'push');
			app.PoweroffALLButton.ButtonPushedFcn = createCallbackFcn(app, @PoweroffALLButtonPushed, true);
			app.PoweroffALLButton.Text = 'Poweroff ALL';
			app.SleepALLButton = uibutton(app.ButtonsGrid, 'push');
			app.SleepALLButton.Enable = 'off';
			app.SleepALLButton.Text = 'Sleep ALL';
			app.HideALLButton = uibutton(app.ButtonsGrid, 'push');
			app.HideALLButton.Enable = 'off';
			app.HideALLButton.Text = 'Hide ALL';
			app.ResetServiceButton = uibutton(app.ButtonsGrid, 'push');
			app.ResetServiceButton.Enable = 'off';
			app.ResetServiceButton.Text = 'Reset-Service';
			app.RemotePortLabel = uilabel(app.GridLayout4);
			app.RemotePortLabel.Text = 'Remote Port';
			app.TabGroup2 = uitabgroup(app.GridLayout2);
			app.GeneralTab = uitab(app.TabGroup2);
			app.GeneralTab.Title = 'General';
			app.GridLayout21 = uigridlayout(app.GeneralTab);
			app.SmartBackgroundLabel = uilabel(app.GridLayout21);
			app.SmartBackgroundLabel.Text = '🌫️ Smart Background';
			app.SmartBackgroundSwitch = uiswitch(app.GridLayout21, 'slider');
			app.SmartBackgroundSwitch.Value = 'On';
			app.PushtoALYXLabel = uilabel(app.GridLayout21);
			app.PushtoALYXLabel.Text = '🗃️ Push to ALYX';
			app.PushtoALYXSwitch = uiswitch(app.GridLayout21, 'slider');
			app.AudioLabel = uilabel(app.GridLayout21);
			app.AudioLabel.Text = '👂🏼 Audio Feedback';
			app.AudioFeedbackSwitch = uiswitch(app.GridLayout21, 'slider');
			app.AudioFeedbackSwitch.Value = 'On';
			app.RewardPumpLabel = uilabel(app.GridLayout21);
			app.RewardPumpLabel.Text = '💦 Reward Pump';
			app.RewardPumpSwitch = uiswitch(app.GridLayout21, 'slider');
			app.RewardPumpSwitch.Value = 'On';
			app.DummyTouchLabel = uilabel(app.GridLayout21);
			app.DummyTouchLabel.Text = '🖱️ Dummy Touch';
			app.DummyTouchSwitch = uiswitch(app.GridLayout21, 'slider');
			app.DebugModeLabel = uilabel(app.GridLayout21);
			app.DebugModeLabel.Text = '🔧 Debug Mode';
			app.DebugModeSwitch = uiswitch(app.GridLayout21, 'slider');
			app.PunishmentTimeOutEditFieldLabel = uilabel(app.GridLayout21);
			app.PunishmentTimeOutEditFieldLabel.Text = 'Punishment Time Out';
			app.PunishmentTimeOut = uieditfield(app.GridLayout21, 'numeric');
			app.PunishmentTimeOut.Limits = [0 100];
			app.PunishmentTimeOut.Value = 10;
			app.folderEditField = uieditfield(app.GridLayout21, 'text');
			app.folderEditField.Tooltip = {'Folder where task resources are stored'};
			app.folderEditField.Value = '~/Code/CageLab-Code/resources';
			app.ChangeResourceFolderButton = uibutton(app.GridLayout21, 'push');
			app.ChangeResourceFolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelection, true);
			app.ChangeResourceFolderButton.Icon = fullfile(pathToMLAPP, 'ui', 'folder-outline.svg');
			app.ChangeResourceFolderButton.Text = 'Change Resource Folder';
			app.InitiateAlyxatsessionstartCheckBox = uicheckbox(app.GridLayout21);
			app.InitiateAlyxatsessionstartCheckBox.Text = 'Initiate Alyx at session start?';
			app.InitiateAlyxatsessionstartCheckBox.Value = true;
			app.UserGuideButton = uibutton(app.GridLayout21, 'push');
			app.UserGuideButton.ButtonPushedFcn = createCallbackFcn(app, @UserGuideButtonPushed, true);
			app.UserGuideButton.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.UserGuideButton.Text = 'User Guide';
			app.ScreenTab = uitab(app.TabGroup2);
			app.ScreenTab.Title = 'Screen';
			app.GridLayout20 = uigridlayout(app.ScreenTab);
			app.ScreenNumberEditFieldLabel = uilabel(app.GridLayout20);
			app.ScreenNumberEditFieldLabel.Text = 'Screen Number';
			app.ScreenNumberEditField = uieditfield(app.GridLayout20, 'numeric');
			app.DistancetoSubjectcmEditFieldLabel = uilabel(app.GridLayout20);
			app.DistancetoSubjectcmEditFieldLabel.Text = 'Distance to Subject (cm)';
			app.DistancetoSubjectcmEditField = uieditfield(app.GridLayout20, 'numeric');
			app.DistancetoSubjectcmEditField.Limits = [1 200];
			app.DistancetoSubjectcmEditField.Value = 25;
			app.PixelDensitypxcmEditFieldLabel = uilabel(app.GridLayout20);
			app.PixelDensitypxcmEditFieldLabel.Text = 'Pixel Density (px/cm)';
			app.PixelDensitypxcmEditField = uieditfield(app.GridLayout20, 'numeric');
			app.PixelDensitypxcmEditField.Value = 88;
			app.DefaultBackgroundColorEditFieldLabel = uilabel(app.GridLayout20);
			app.DefaultBackgroundColorEditFieldLabel.Text = 'Default Background Color';
			app.DefaultBackgroundColorEditField = uieditfield(app.GridLayout20, 'text');
			app.DefaultBackgroundColorEditField.Value = '[ 0.5 0.5 0.5 ]';
			app.DisableSyncSwitchLabel = uilabel(app.GridLayout20);
			app.DisableSyncSwitchLabel.Text = 'Disable Sync?';
			app.DisableSyncSwitch = uiswitch(app.GridLayout20, 'slider');
			app.DisableSyncSwitch.Value = 'On';
			app.OpenGLBlendLabel = uilabel(app.GridLayout20);
			app.OpenGLBlendLabel.Text = 'OpenGL Blending?';
			app.OpenGLBlendingSwitch = uiswitch(app.GridLayout20, 'slider');
			app.OpenGLBlendingSwitch.Value = 'On';
			app.LightsOutSwitchLabel = uilabel(app.GridLayout20);
			app.LightsOutSwitchLabel.Enable = 'off';
			app.LightsOutSwitchLabel.Text = 'Lights Out?';
			app.LightsOutSwitch = uiswitch(app.GridLayout20, 'slider');
			app.LightsOutSwitch.Enable = 'off';
			app.LightsOutHourEditFieldLabel = uilabel(app.GridLayout20);
			app.LightsOutHourEditFieldLabel.Text = 'Lights Out Hour';
			app.LightsOutHourEditField = uieditfield(app.GridLayout20, 'numeric');
			app.LightsOutHourEditField.Value = 22;
			app.HideScreenColorEditFieldLabel = uilabel(app.GridLayout20);
			app.HideScreenColorEditFieldLabel.Text = 'Hide Screen Color';
			app.HideScreenColorEditField = uieditfield(app.GridLayout20, 'text');
			app.HideScreenColorEditField.Value = '[ 0.7 1 0.3 ]';
			app.HighPrioritySwitchLabel = uilabel(app.GridLayout20);
			app.HighPrioritySwitchLabel.Text = 'High Priority?';
			app.HighPrioritySwitch = uiswitch(app.GridLayout20, 'slider');
			app.HighPrioritySwitch.Value = 'On';
			app.UseVulkanSwitchLabel = uilabel(app.GridLayout20);
			app.UseVulkanSwitchLabel.Text = 'Use Vulkan?';
			app.UseVulkanSwitch = uiswitch(app.GridLayout20, 'slider');
			app.AudioPumpTab = uitab(app.TabGroup2);
			app.AudioPumpTab.Title = 'Audio/Pump';
			app.GridLayout19 = uigridlayout(app.AudioPumpTab);
			app.AudioDeviceEditFieldLabel = uilabel(app.GridLayout19);
			app.AudioDeviceEditFieldLabel.Text = 'Audio Device';
			app.AudioDeviceEditField = uieditfield(app.GridLayout19, 'text');
			app.AudioDeviceEditField.Value = '[ ]';
			app.AudioVolumeEditFieldLabel = uilabel(app.GridLayout19);
			app.AudioVolumeEditFieldLabel.Text = 'Audio Volume';
			app.AudioVolumeEditField = uieditfield(app.GridLayout19, 'numeric');
			app.AudioVolumeEditField.Limits = [0 1];
			app.AudioVolumeEditField.Value = 1;
			app.IncorrectBeepHzEditFieldLabel = uilabel(app.GridLayout19);
			app.IncorrectBeepHzEditFieldLabel.Text = 'Incorrect Beep (Hz)';
			app.IncorrectBeepHzEditField = uieditfield(app.GridLayout19, 'numeric');
			app.IncorrectBeepHzEditField.Limits = [20 20000];
			app.IncorrectBeepHzEditField.Value = 300;
			app.Label = uilabel(app.GridLayout19);
			app.Label.Text = 'Correct Beep (Hz)';
			app.CorrectBeepHzEditField = uieditfield(app.GridLayout19, 'numeric');
			app.CorrectBeepHzEditField.Limits = [20 20000];
			app.CorrectBeepHzEditField.Value = 1800;
			app.RewardPumpTimemsLabel = uilabel(app.GridLayout19);
			app.RewardPumpTimemsLabel.Text = 'Reward Pump Time (ms)';
			app.PumpTime = uieditfield(app.GridLayout19, 'numeric');
			app.PumpTime.Limits = [0 1000];
			app.PumpTime.RoundFractionalValues = 'on';
			app.PumpTime.Value = 100;
			app.TouchPanelTab = uitab(app.TabGroup2);
			app.TouchPanelTab.Title = 'Touch Panel';
			app.GridLayout10 = uigridlayout(app.TouchPanelTab);
			app.TouchDeviceIDEditFieldLabel = uilabel(app.GridLayout10);
			app.TouchDeviceIDEditFieldLabel.Text = 'Touch Device ID';
			app.TouchDeviceIDEditField = uieditfield(app.GridLayout10, 'numeric');
			app.TouchDeviceIDEditField.AllowEmpty = 'on';
			app.TouchDeviceIDEditField.Tooltip = {'Most systems should leave this at 1'};
			app.TouchDeviceIDEditField.Placeholder = '1';
			app.TouchDeviceIDEditField.Value = 1;
			app.NegateTouchWindowCheckBox = uicheckbox(app.GridLayout10);
			app.NegateTouchWindowCheckBox.Text = 'Negate Touch Window';
			app.NegateTouchWindowCheckBox.Value = true;
			app.StrictmodeCheckBox = uicheckbox(app.GridLayout10);
			app.StrictmodeCheckBox.Text = 'Strict mode';
			app.StrictmodeCheckBox.Value = true;
			app.DrainMultipleEventsCheckBox = uicheckbox(app.GridLayout10);
			app.DrainMultipleEventsCheckBox.Tooltip = {'The touch screen is asynchronous to the rest of the task, and events can build up. Make sure this is OFF to process every event inthe queue (more reliable, but possibly slower)...'};
			app.DrainMultipleEventsCheckBox.Text = 'Drain Multiple Events?';
			app.IgnoreExtraTouchesCheckBox = uicheckbox(app.GridLayout10);
			app.IgnoreExtraTouchesCheckBox.Enable = 'off';
			app.IgnoreExtraTouchesCheckBox.Text = 'Ignore Extra Touches?';
			app.IgnoreExtraTouchesCheckBox.Value = true;
			app.ExclusionZoneEditFieldLabel = uilabel(app.GridLayout10);
			app.ExclusionZoneEditFieldLabel.Text = 'Exclusion Zone';
			app.ExclusionZoneEditField = uieditfield(app.GridLayout10, 'text');
			app.ExclusionZoneEditField.Value = '[ ]';
			app.NegationbufferdegEditFieldLabel = uilabel(app.GridLayout10);
			app.NegationbufferdegEditFieldLabel.Text = 'Negation buffer (deg)';
			app.NegationbufferdegEditField = uieditfield(app.GridLayout10, 'numeric');
			app.NegationbufferdegEditField.Value = 2;
			app.TouchDeviceNameEditFieldLabel = uilabel(app.GridLayout10);
			app.TouchDeviceNameEditFieldLabel.Text = 'Touch Device Name';
			app.TouchDeviceNameEditField = uieditfield(app.GridLayout10, 'text');
			app.TouchDeviceNameEditField.Tooltip = {'The name of the touch device as seen in `xinput list`'};
			app.TouchDeviceNameEditField.Placeholder = 'ILITEK-TP';
			app.TouchDeviceNameEditField.Value = 'ILITEK-TP';
			app.VerboseCheckBox = uicheckbox(app.GridLayout10);
			app.VerboseCheckBox.Text = 'Verbose';
			app.StairCaseTab = uitab(app.TabGroup2);
			app.StairCaseTab.Title = 'StairCase';
			app.GridLayout_Staircase = uigridlayout(app.StairCaseTab);
			app.StepForwardNTrialsLabel = uilabel(app.GridLayout_Staircase);
			app.StepForwardNTrialsLabel.Text = 'Step Forward N Trials';
			app.StepForward = uieditfield(app.GridLayout_Staircase, 'numeric');
			app.StepForward.RoundFractionalValues = 'on';
			app.StepForward.Value = 15;
			app.IncorrectThresholdEditFieldLabel_2 = uilabel(app.GridLayout_Staircase);
			app.IncorrectThresholdEditFieldLabel_2.Text = 'Incorrect Threshold';
			app.IncorrectThreshold = uieditfield(app.GridLayout_Staircase, 'numeric');
			app.IncorrectThreshold.Limits = [0 1];
			app.IncorrectThreshold.Value = 0.2;
			app.MaxPhasesStepBackEditFieldLabel_2 = uilabel(app.GridLayout_Staircase);
			app.MaxPhasesStepBackEditFieldLabel_2.Text = 'Max Phases Step Back';
			app.MaxPhasesStepBack = uieditfield(app.GridLayout_Staircase, 'numeric');
			app.MaxPhasesStepBack.RoundFractionalValues = 'on';
			app.MaxPhasesStepBack.Value = 1;
			app.CorrectThresholdEditFieldLabel_2 = uilabel(app.GridLayout_Staircase);
			app.CorrectThresholdEditFieldLabel_2.Text = ' Correct Threshold';
			app.CorrectThreshold = uieditfield(app.GridLayout_Staircase, 'numeric');
			app.CorrectThreshold.Limits = [0 1];
			app.CorrectThreshold.Value = 0.8;
			app.Note_3 = uilabel(app.GridLayout_Staircase);
			app.Note_3.Text = 'For training phases, the general approach is to use an asymmetric staircase. Every N Step forward trials if performance is > Correct Threshold then the training phase increments, if it is < incorrect threshold it decrements (but only by max steps back to stop subject deliberately going back to easier phases). This adaptive trains moving a subject forwards without falling backwards more than N phases. Total rewards limits the total number of rewards in one session, Inf means do not check this. Easy trials are given in some tasks with % probability if performance is <= incorrect threshold to encourage subject to keep engaging (but doesn''t adjust performance rate).';
			app.TotalTrialsRewardsLabel = uilabel(app.GridLayout_Staircase);
			app.TotalTrialsRewardsLabel.Text = 'Stop After N Trials?';
			app.TotalRewards = uieditfield(app.GridLayout_Staircase, 'numeric');
			app.TotalRewards.Limits = [1 Inf];
			app.TotalRewards.RoundFractionalValues = 'on';
			app.TotalRewards.Value = Inf;
			app.EasyTrialsThresholdLabel = uilabel(app.GridLayout_Staircase);
			app.EasyTrialsThresholdLabel.Text = 'Easy Trials Probability';
			app.EasyTrialsProbability = uieditfield(app.GridLayout_Staircase, 'numeric');
			app.EasyTrialsProbability.Limits = [0 1];
			app.EasyTrialsProbability.Value = 0.5;
			app.TotalOnlyCorrect = uicheckbox(app.GridLayout_Staircase);
			app.TotalOnlyCorrect.Text = 'Stop Trials Number only includes Correct (rewarded) trials?';
			app.TotalOnlyCorrect.Value = true;
			app.AlyxTab = uitab(app.TabGroup);
			app.AlyxTab.Title = '🗃️ Alyx';
			app.GridLayout11 = uigridlayout(app.AlyxTab);
			app.ALYXInterfacePanel = uipanel(app.GridLayout11);
			app.ALYXInterfacePanel.Title = 'ALYX Management & Session Metadata';
			app.AlyxLayout = uigridlayout(app.ALYXInterfacePanel);
			app.text4554 = uilabel(app.AlyxLayout);
			app.text4554.Text = 'ALYX IP & Port';
			app.text4554_2 = uilabel(app.AlyxLayout);
			app.text4554_2.Text = 'ALYX Login';
			app.OKAlyxResults = uitable(app.AlyxLayout);
			app.OKAlyxResults.ColumnName = {'ID'; 'Start'; 'End'; 'Lab'};
			app.OKAlyxResults.RowName = {};
			app.OKAlyxRequestButton = uibutton(app.AlyxLayout, 'push');
			app.OKAlyxRequestButton.ButtonPushedFcn = createCallbackFcn(app, @AlyxRequestButtonPushed, true);
			app.OKAlyxRequestButton.Icon = fullfile(pathToMLAPP, 'ui', 'send.svg');
			app.OKAlyxRequestButton.Enable = 'off';
			app.OKAlyxRequestButton.Text = '';
			app.OKAlyxLOGOUTButton = uibutton(app.AlyxLayout, 'push');
			app.OKAlyxLOGOUTButton.ButtonPushedFcn = createCallbackFcn(app, @AlyxLOGOUTButtonPushed, true);
			app.OKAlyxLOGOUTButton.Icon = fullfile(pathToMLAPP, 'ui', 'link.circle.off.svg');
			app.OKAlyxLOGOUTButton.Text = 'Log out';
			app.OKAlyxLOGINButton = uibutton(app.AlyxLayout, 'push');
			app.OKAlyxLOGINButton.ButtonPushedFcn = createCallbackFcn(app, @AlyxLOGINButtonPushed, true);
			app.OKAlyxLOGINButton.Icon = fullfile(pathToMLAPP, 'ui', 'link.circle.svg');
			app.OKAlyxLOGINButton.Text = 'Login to Alyx';
			app.OKAlyxProcedure = uidropdown(app.AlyxLayout);
			app.OKAlyxProcedure.Items = {'Behavior training/tasks', 'Imaging', 'Intraocular injection', 'Optical stimulation'};
			app.OKAlyxProcedure.Value = 'Behavior training/tasks';
			app.OKAlyxProject = uidropdown(app.AlyxLayout);
			app.OKAlyxProject.Items = {'Test-Training', 'Touch-Training'};
			app.OKAlyxProject.Value = 'Test-Training';
			app.text4554_3 = uilabel(app.AlyxLayout);
			app.text4554_3.Text = 'Project';
			app.OKAlyxBrainRegion = uidropdown(app.AlyxLayout);
			app.OKAlyxBrainRegion.Items = {'Cerebral cortex', 'Thalamus', 'Hippocampus', 'Basal Ganglia'};
			app.OKAlyxBrainRegion.Value = 'Cerebral cortex';
			app.text4554_4 = uilabel(app.AlyxLayout);
			app.text4554_4.Text = 'Brain Region';
			app.TaskProtocolEditFieldLabel = uilabel(app.AlyxLayout);
			app.TaskProtocolEditFieldLabel.Text = 'Task Protocol';
			app.OKAlyxTaskProtocol = uieditfield(app.AlyxLayout, 'text');
			app.alyxIP = uieditfield(app.AlyxLayout, 'text');
			app.alyxIP.Value = 'http://172.16.102.30:8000';
			app.alyxUser = uieditfield(app.AlyxLayout, 'text');
			app.alyxUser.Value = 'admin';
			app.ProcedureLabel = uilabel(app.AlyxLayout);
			app.ProcedureLabel.Text = 'Procedure';
			app.OKSubject = uieditfield(app.AlyxLayout, 'text');
			app.OKSubject.ValueChangedFcn = createCallbackFcn(app, @OKSubjectValueChanged, true);
			app.OKSubject.Tag = 'OKTrainingName';
			app.OKSubject.Tooltip = {'Unique Subject Name / ID'};
			app.OKSubject.Value = 'Simulcra';
			app.text209 = uilabel(app.AlyxLayout);
			app.text209.Tag = 'text209';
			app.text209.Text = 'Subject 🧠';
			app.OKResearcher = uieditfield(app.AlyxLayout, 'text');
			app.OKResearcher.Tag = 'OKTrainingResearcherName';
			app.OKResearcher.Tooltip = {'Experimenter name'};
			app.OKResearcher.Value = 'Jane Doe';
			app.text4554_5 = uilabel(app.AlyxLayout);
			app.text4554_5.Tag = 'text4554';
			app.text4554_5.Text = 'Researcher🧑🏻‍🔬';
			app.OKLabName = uieditfield(app.AlyxLayout, 'text');
			app.OKLabName.Tag = 'OKTrainingName';
			app.OKLabName.Tooltip = {'Lab / Group name'};
			app.OKLabName.Value = 'Lab';
			app.text209_2 = uilabel(app.AlyxLayout);
			app.text209_2.Tag = 'text209';
			app.text209_2.Text = 'Lab 🥼';
			app.OKLabLocation = uieditfield(app.AlyxLayout, 'text');
			app.OKLabLocation.Tag = 'OKTrainingResearcherName';
			app.OKLabLocation.Tooltip = {'Room / building where experiment is being performed'};
			app.OKLabLocation.Value = 'Room 10';
			app.text4554_6 = uilabel(app.AlyxLayout);
			app.text4554_6.Tag = 'text4554';
			app.text4554_6.Text = 'Location 🗺️';
			app.AlyxDatastoreDatabaseLabel = uilabel(app.AlyxLayout);
			app.AlyxDatastoreDatabaseLabel.Text = 'Alyx + Datastore Database:';
			app.SubjectWeight = uieditfield(app.AlyxLayout, 'text');
			app.SubjectWeight.Tag = 'OKTrainingName';
			app.SubjectWeight.Enable = 'off';
			app.SubjectWeight.Tooltip = {'Unique Subject Name / ID'};
			app.SubjectWeight.Placeholder = '...weight in kg';
			app.AlyxAddWeight = uibutton(app.AlyxLayout, 'push');
			app.AlyxAddWeight.ButtonPushedFcn = createCallbackFcn(app, @AlyxAddWeightPushed, true);
			app.AlyxAddWeight.Icon = fullfile(pathToMLAPP, 'ui', 'plus.svg');
			app.AlyxAddWeight.Enable = 'off';
			app.AlyxAddWeight.Text = 'Weight';
			app.SubjectWater = uieditfield(app.AlyxLayout, 'text');
			app.SubjectWater.Tag = 'OKTrainingName';
			app.SubjectWater.Enable = 'off';
			app.SubjectWater.Tooltip = {'Unique Subject Name / ID'};
			app.SubjectWater.Placeholder = '...water given';
			app.AlyxAddWater = uibutton(app.AlyxLayout, 'push');
			app.AlyxAddWater.Icon = fullfile(pathToMLAPP, 'ui', 'plus.svg');
			app.AlyxAddWater.Enable = 'off';
			app.AlyxAddWater.Text = 'Water';
			app.SessionMetadataLabel = uilabel(app.AlyxLayout);
			app.SessionMetadataLabel.Text = 'Session Metadata:';
			app.PasswordReset = uidropdown(app.AlyxLayout);
			app.PasswordReset.Items = {'Set Alyx Password', 'Set S3 ID', 'Set S3 KEY'};
			app.PasswordReset.Value = 'Set Alyx Password';
			app.ChangeButton = uibutton(app.AlyxLayout, 'push');
			app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
			app.ChangeButton.Text = 'Change';
			app.OKDataRepo = uieditfield(app.AlyxLayout, 'text');
			app.OKDataRepo.Value = 'http://172.16.102.77:9000';
			app.OKDataBucket = uieditfield(app.AlyxLayout, 'text');
			app.OKDataBucket.Value = 'cognitionplatform';
			app.text4554_7 = uilabel(app.AlyxLayout);
			app.text4554_7.Text = 'Data Repository URL';
			app.text4554_8 = uilabel(app.AlyxLayout);
			app.text4554_8.Text = 'Data-Repository Name';
			app.OKAlyxRequest = CompactEditableComboBox(app.AlyxLayout);
			app.SendDatabaseRequestLabel = uilabel(app.AlyxLayout);
			app.SendDatabaseRequestLabel.Text = 'Send Database Request:';
			app.alyxGUIOpen = uibutton(app.AlyxLayout, 'push');
			app.alyxGUIOpen.ButtonPushedFcn = createCallbackFcn(app, @alyxGUIOpenButtonPushed, true);
			app.alyxGUIOpen.Tooltip = {'Open more Alyx Tools...'};
			app.alyxGUIOpen.Text = '...';
			app.TouchTab = uitab(app.TabGroup);
			app.TouchTab.Title = '👆🏻 Touch';
			app.GridLayout12 = uigridlayout(app.TouchTab);
			app.AutomatedTouchTrainingPanel = uipanel(app.GridLayout12);
			app.AutomatedTouchTrainingPanel.Title = 'Automated Touch Training';
			app.GridLayout9 = uigridlayout(app.AutomatedTouchTrainingPanel);
			app.Phase138Label = uilabel(app.GridLayout9);
			app.Phase138Label.Text = 'Initial Phase (1 - 38)';
			app.Phase_TOUCH = uieditfield(app.GridLayout9, 'numeric');
			app.Phase_TOUCH.Limits = [1 38];
			app.Phase_TOUCH.RoundFractionalValues = 'on';
			app.Phase_TOUCH.Value = 1;
			app.InitialTargetSizeEditFieldLabel = uilabel(app.GridLayout9);
			app.InitialTargetSizeEditFieldLabel.Text = 'Initial Target Size';
			app.InitialTargetSize_TOUCH = uieditfield(app.GridLayout9, 'numeric');
			app.InitialTargetSize_TOUCH.Value = 55;
			app.FinalTargetSizeEditFieldLabel = uilabel(app.GridLayout9);
			app.FinalTargetSizeEditFieldLabel.Text = 'Final Target Size';
			app.FinalTargetSize_TOUCH = uieditfield(app.GridLayout9, 'numeric');
			app.FinalTargetSize_TOUCH.Value = 8;
			app.RandomRewardTimerEditFieldLabel = uilabel(app.GridLayout9);
			app.RandomRewardTimerEditFieldLabel.Text = 'Random Reward Timer';
			app.RandomRewardTimer_TOUCH = uieditfield(app.GridLayout9, 'numeric');
			app.RandomRewardTimer_TOUCH.Value = 120;
			app.RandomRewardProbabilityEditFieldLabel = uilabel(app.GridLayout9);
			app.RandomRewardProbabilityEditFieldLabel.Text = 'Random Reward Probability';
			app.RandomRewardProbability_TOUCH = uieditfield(app.GridLayout9, 'numeric');
			app.RandomRewardProbability_TOUCH.Limits = [0 1];
			app.RandomRewardProbability_TOUCH.Value = 0.25;
			app.TargetDiscColorEditFieldLabel = uilabel(app.GridLayout9);
			app.TargetDiscColorEditFieldLabel.Text = 'Target (Disc) Color';
			app.TargetDiscColor_TOUCH = uieditfield(app.GridLayout9, 'text');
			app.TargetDiscColor_TOUCH.Value = '[ 1 0.9 0.3 ]';
			app.TrialTimeEditFieldLabel = uilabel(app.GridLayout9);
			app.TrialTimeEditFieldLabel.Text = 'Trial Time';
			app.TrialTime_TOUCH = uieditfield(app.GridLayout9, 'numeric');
			app.TrialTime_TOUCH.Value = 5;
			app.DifficultyDropDownLabel = uilabel(app.GridLayout9);
			app.DifficultyDropDownLabel.Text = 'Difficulty';
			app.Difficulty_TOUCH = uidropdown(app.GridLayout9);
			app.Difficulty_TOUCH.Items = {'Simple', 'Normal'};
			app.Difficulty_TOUCH.Value = 'Simple';
			app.TargetTypeDropDownLabel = uilabel(app.GridLayout9);
			app.TargetTypeDropDownLabel.Text = 'Target Type';
			app.TargetType_TOUCH = uidropdown(app.GridLayout9);
			app.TargetType_TOUCH.Items = {'Picture', 'Disc'};
			app.TargetType_TOUCH.Value = 'Picture';
			app.STARTButton_TOUCH = uibutton(app.GridLayout9, 'push');
			app.STARTButton_TOUCH.ButtonPushedFcn = createCallbackFcn(app, @STARTTT, true);
			app.STARTButton_TOUCH.Tag = 'touchTraining';
			app.STARTButton_TOUCH.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.STARTButton_TOUCH.Text = 'START';
			app.StopButton_TOUCH = uibutton(app.GridLayout9, 'push');
			app.StopButton_TOUCH.ButtonPushedFcn = createCallbackFcn(app, @STOPButton, true);
			app.StopButton_TOUCH.Tag = 'touchTraining';
			app.StopButton_TOUCH.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_TOUCH.Text = 'Stop';
			app.rewardnote = uilabel(app.GridLayout9);
			app.rewardnote.Text = 'Random rewards only occur in Phase 1, and this encourages the monkey to come forwards and engage with CageLab. If the monkey is not engaging, every X seconds a reward will be given with Y probability.';
			app.StatusButton = uibutton(app.GridLayout9, 'push');
			app.StatusButton.ButtonPushedFcn = createCallbackFcn(app, @STATUSButton, true);
			app.StatusButton.Tag = 'touchTraining';
			app.StatusButton.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton.Text = 'Status?';
			app.TrialTimeEditField_8Label_2 = uilabel(app.GridLayout9);
			app.TrialTimeEditField_8Label_2.Text = 'InterTrial Time';
			app.InterTrialTime_TOUCH = uieditfield(app.GridLayout9, 'numeric');
			app.InterTrialTime_TOUCH.Value = 1;
			app.rewardnote_2 = uilabel(app.GridLayout9);
			app.rewardnote_2.Text = 'See staircase options to control when to move to the next phase within the session.';
			app.MTSTab = uitab(app.TabGroup);
			app.MTSTab.Title = '🔍 MTS';
			app.GridLayout13 = uigridlayout(app.MTSTab);
			app.NonMatchToSampleMTSPanel = uipanel(app.GridLayout13);
			app.NonMatchToSampleMTSPanel.Title = '[Non]Match To Sample (MTS)';
			app.GridLayout9_2 = uigridlayout(app.NonMatchToSampleMTSPanel);
			app.NumberofdistractorsEditFieldLabel = uilabel(app.GridLayout9_2);
			app.NumberofdistractorsEditFieldLabel.Text = 'Number of distractors';
			app.Numberofdistractors_MTS = uieditfield(app.GridLayout9_2, 'numeric');
			app.Numberofdistractors_MTS.Limits = [0 4];
			app.Numberofdistractors_MTS.Value = 2;
			app.ObjectSizeEditFieldLabel = uilabel(app.GridLayout9_2);
			app.ObjectSizeEditFieldLabel.Text = 'Object Size';
			app.ObjectSize_MTS = uieditfield(app.GridLayout9_2, 'numeric');
			app.ObjectSize_MTS.Value = 8;
			app.ObjectSeparationEditFieldLabel = uilabel(app.GridLayout9_2);
			app.ObjectSeparationEditFieldLabel.Text = 'Object Separation';
			app.ObjectSeparation_MTS = uieditfield(app.GridLayout9_2, 'numeric');
			app.ObjectSeparation_MTS.Value = 10;
			app.TrialTimeEditField_2Label = uilabel(app.GridLayout9_2);
			app.TrialTimeEditField_2Label.Text = 'Trial Time';
			app.TrialTime_MTS = uieditfield(app.GridLayout9_2, 'numeric');
			app.TrialTime_MTS.Value = 5;
			app.TaskStageDropDownLabel = uilabel(app.GridLayout9_2);
			app.TaskStageDropDownLabel.Text = 'Task Stage';
			app.TaskStage_MTS = uidropdown(app.GridLayout9_2);
			app.TaskStage_MTS.Items = {'Training', 'Full Task'};
			app.TaskStage_MTS.Value = 'Full Task';
			app.ObjectTypeDropDownLabel = uilabel(app.GridLayout9_2);
			app.ObjectTypeDropDownLabel.Text = 'Object Type';
			app.ObjectType_MTS = uidropdown(app.GridLayout9_2);
			app.ObjectType_MTS.Items = {'Quaddles', 'Fractals', 'Flowers'};
			app.ObjectType_MTS.Value = 'Quaddles';
			app.STARTMTSButton = uibutton(app.GridLayout9_2, 'push');
			app.STARTMTSButton.ButtonPushedFcn = createCallbackFcn(app, @STARTMTS, true);
			app.STARTMTSButton.Tag = 'touchTraining';
			app.STARTMTSButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.STARTMTSButton.Text = 'START MTS';
			app.StopButton_MTS = uibutton(app.GridLayout9_2, 'push');
			app.StopButton_MTS.ButtonPushedFcn = createCallbackFcn(app, @STOPButton, true);
			app.StopButton_MTS.Tag = 'touchTraining';
			app.StopButton_MTS.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_MTS.Text = 'Stop';
			app.InitiationPointPositionxyEditField_2Label = uilabel(app.GridLayout9_2);
			app.InitiationPointPositionxyEditField_2Label.Text = 'Initiation Point Position (x,y)';
			app.InitiationPointPositionxy_MTS = uieditfield(app.GridLayout9_2, 'text');
			app.InitiationPointPositionxy_MTS.Value = '[ 0 6 ]';
			app.InitiationPointColourEditField_2Label = uilabel(app.GridLayout9_2);
			app.InitiationPointColourEditField_2Label.Text = 'Initiation Point Colour';
			app.InitiationPointColour_MTS = uieditfield(app.GridLayout9_2, 'text');
			app.InitiationPointColour_MTS.Value = '[ 1 0.4 0.3 ]';
			app.InitiationPointSizeEditField_2Label = uilabel(app.GridLayout9_2);
			app.InitiationPointSizeEditField_2Label.Text = 'Initiation Point Size';
			app.InitiationPointSize_MTS = uieditfield(app.GridLayout9_2, 'numeric');
			app.InitiationPointSize_MTS.Value = 8;
			app.SampleYPositionEditFieldLabel = uilabel(app.GridLayout9_2);
			app.SampleYPositionEditFieldLabel.Text = 'Sample Y Position';
			app.SampleYPosition_MTS = uieditfield(app.GridLayout9_2, 'numeric');
			app.SampleYPosition_MTS.Value = -9;
			app.DistractorYPositionEditFieldLabel = uilabel(app.GridLayout9_2);
			app.DistractorYPositionEditFieldLabel.Text = 'Distractor Y Position';
			app.DistractorYPosition_MTS = uieditfield(app.GridLayout9_2, 'numeric');
			app.DistractorYPosition_MTS.Value = -1;
			app.InitiationHoldTimeEditFieldLabel = uilabel(app.GridLayout9_2);
			app.InitiationHoldTimeEditFieldLabel.Text = 'Initiation Hold Time';
			app.InitiationHoldTime_MTS = uieditfield(app.GridLayout9_2, 'numeric');
			app.InitiationHoldTime_MTS.Value = 0.03;
			app.TargetHoldTimeEditFieldLabel = uilabel(app.GridLayout9_2);
			app.TargetHoldTimeEditFieldLabel.Text = 'Target Hold Time';
			app.TargetHoldTime_MTS = uieditfield(app.GridLayout9_2, 'numeric');
			app.TargetHoldTime_MTS.Value = 0.05;
			app.StatusButton_MTS = uibutton(app.GridLayout9_2, 'push');
			app.StatusButton_MTS.ButtonPushedFcn = createCallbackFcn(app, @STATUSButton, true);
			app.StatusButton_MTS.Tag = 'touchTraining';
			app.StatusButton_MTS.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton_MTS.Text = 'Status?';
			app.NonMatchLabel_2 = uilabel(app.GridLayout9_2);
			app.NonMatchLabel_2.Text = 'Non-Match?';
			app.NonMatch_MTS = uiswitch(app.GridLayout9_2, 'slider');
			app.DMTSTab = uitab(app.TabGroup);
			app.DMTSTab.Title = '⏸️🔍 DMTS';
			app.GridLayout14 = uigridlayout(app.DMTSTab);
			app.DelayedNonMatchToSampleDMTSPanel = uipanel(app.GridLayout14);
			app.DelayedNonMatchToSampleDMTSPanel.Title = 'Delayed [Non]Match To Sample (DMTS)';
			app.GridLayout9_5 = uigridlayout(app.DelayedNonMatchToSampleDMTSPanel);
			app.NumberofdistractorsEditField_2Label = uilabel(app.GridLayout9_5);
			app.NumberofdistractorsEditField_2Label.Text = 'Number of distractors';
			app.Numberofdistractors_DMTS = uieditfield(app.GridLayout9_5, 'numeric');
			app.Numberofdistractors_DMTS.Limits = [0 4];
			app.Numberofdistractors_DMTS.Value = 4;
			app.ObjectSizeLabel = uilabel(app.GridLayout9_5);
			app.ObjectSizeLabel.Text = 'Object Size (°)';
			app.ObjectSize_DMTS = uieditfield(app.GridLayout9_5, 'numeric');
			app.ObjectSize_DMTS.Value = 8;
			app.ObjectSeparationdegLabel = uilabel(app.GridLayout9_5);
			app.ObjectSeparationdegLabel.Text = 'Object Separation (°)';
			app.ObjectSeparation_DMTS = uieditfield(app.GridLayout9_5, 'numeric');
			app.ObjectSeparation_DMTS.Value = 10;
			app.TrialTimesLabel = uilabel(app.GridLayout9_5);
			app.TrialTimesLabel.Text = 'Trial Time (s)';
			app.TrialTime_DMTS = uieditfield(app.GridLayout9_5, 'numeric');
			app.TrialTime_DMTS.Value = 5;
			app.TaskStageDropDown_4Label = uilabel(app.GridLayout9_5);
			app.TaskStageDropDown_4Label.Text = 'Task Stage';
			app.TaskStage_DMTS = uidropdown(app.GridLayout9_5);
			app.TaskStage_DMTS.Items = {'Training', 'Full Task'};
			app.TaskStage_DMTS.Value = 'Full Task';
			app.ObjectTypeDropDown_4Label = uilabel(app.GridLayout9_5);
			app.ObjectTypeDropDown_4Label.Text = 'Object Type';
			app.ObjectType_DMTS = uidropdown(app.GridLayout9_5);
			app.ObjectType_DMTS.Items = {'Quaddles', 'Fractals', 'Flowers'};
			app.ObjectType_DMTS.Value = 'Quaddles';
			app.STARTDMTSButton = uibutton(app.GridLayout9_5, 'push');
			app.STARTDMTSButton.ButtonPushedFcn = createCallbackFcn(app, @STARTDMTS, true);
			app.STARTDMTSButton.Tag = 'touchTraining';
			app.STARTDMTSButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.STARTDMTSButton.Text = 'START DMTS';
			app.StopButton_DMTS = uibutton(app.GridLayout9_5, 'push');
			app.StopButton_DMTS.ButtonPushedFcn = createCallbackFcn(app, @STOPButton, true);
			app.StopButton_DMTS.Tag = 'touchTraining';
			app.StopButton_DMTS.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_DMTS.Text = 'Stop';
			app.InitiationPointPositionxyLabel = uilabel(app.GridLayout9_5);
			app.InitiationPointPositionxyLabel.Text = 'Initiation Point Position (x°,y°)';
			app.InitPosition_DMTS = uieditfield(app.GridLayout9_5, 'text');
			app.InitPosition_DMTS.Value = '[ 0 6 ]';
			app.InitiationPointColourEditField_3Label = uilabel(app.GridLayout9_5);
			app.InitiationPointColourEditField_3Label.Text = 'Initiation Point Colour';
			app.InitColour_DMTS = uieditfield(app.GridLayout9_5, 'text');
			app.InitColour_DMTS.Value = '[ 1 0.4 0.3 ]';
			app.InitHoldTime_DMTS = uieditfield(app.GridLayout9_5, 'numeric');
			app.InitHoldTime_DMTS.Value = 0.03;
			app.InitiationPointSizeLabel = uilabel(app.GridLayout9_5);
			app.InitiationPointSizeLabel.Text = 'Initiation Point Size (°)';
			app.InitSize_DMTS = uieditfield(app.GridLayout9_5, 'numeric');
			app.InitSize_DMTS.Value = 8;
			app.SampleYPositionLabel = uilabel(app.GridLayout9_5);
			app.SampleYPositionLabel.Text = 'Sample Y Position (°)';
			app.SampleYPosition_DMTS = uieditfield(app.GridLayout9_5, 'numeric');
			app.SampleYPosition_DMTS.Value = -9;
			app.DistractorYPositionLabel = uilabel(app.GridLayout9_5);
			app.DistractorYPositionLabel.Text = 'Distractor Y Position (°)';
			app.DistractorYPosition_DMTS = uieditfield(app.GridLayout9_5, 'numeric');
			app.DistractorYPosition_DMTS.Value = -1;
			app.DelayTimexorminmaxLabel = uilabel(app.GridLayout9_5);
			app.DelayTimexorminmaxLabel.Text = 'Delay Time Range (s) ';
			app.DelayTime_DMTS = uieditfield(app.GridLayout9_5, 'text');
			app.DelayTime_DMTS.Tooltip = {'Delay time can be a single value or a range N=2 [min max] or fixed values like [ 1 2 4 8]'};
			app.DelayTime_DMTS.Value = '[ 0.2 0.4 ]';
			app.NonMatchLabel = uilabel(app.GridLayout9_5);
			app.NonMatchLabel.Text = 'Non-Match?';
			app.NonMatch_DMTS = uiswitch(app.GridLayout9_5, 'slider');
			app.NonMatch_DMTS.ValueChangedFcn = createCallbackFcn(app, @NonMatch_DMTSValueChanged, true);
			app.SampleTimexorminmaxLabel = uilabel(app.GridLayout9_5);
			app.SampleTimexorminmaxLabel.Text = 'Sample Time Range (s) ';
			app.SampleDisplayTime_DMTS = uieditfield(app.GridLayout9_5, 'text');
			app.SampleDisplayTime_DMTS.Tooltip = {'Sample time can be a single value or a range [min max] or fixed values like [ 1 2 4 8]'};
			app.SampleDisplayTime_DMTS.Value = '0.3';
			app.InitiationHoldTimesLabel_2 = uilabel(app.GridLayout9_5);
			app.InitiationHoldTimesLabel_2.Text = 'Initiation Hold Time (s)';
			app.TargetHoldTimesLabel_8 = uilabel(app.GridLayout9_5);
			app.TargetHoldTimesLabel_8.Text = 'Target Hold Time (s)';
			app.TargetHoldTime_DMTS = uieditfield(app.GridLayout9_5, 'numeric');
			app.TargetHoldTime_DMTS.Value = 0.05;
			app.StatusButton_3 = uibutton(app.GridLayout9_5, 'push');
			app.StatusButton_3.ButtonPushedFcn = createCallbackFcn(app, @STATUSButton, true);
			app.StatusButton_3.Tag = 'touchTraining';
			app.StatusButton_3.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton_3.Text = 'Status?';
			app.AddDelayPeriodDistractorsLabel = uilabel(app.GridLayout9_5);
			app.AddDelayPeriodDistractorsLabel.Text = 'Add Delay Period Distractors?';
			app.DelayDistractors_DMTS = uiswitch(app.GridLayout9_5, 'slider');
			app.TargetYPositionLabel_5 = uilabel(app.GridLayout9_5);
			app.TargetYPositionLabel_5.Enable = 'off';
			app.TargetYPositionLabel_5.Text = 'Object spread (°) around init';
			app.DistractorAngleSpread_DMTS = uieditfield(app.GridLayout9_5, 'numeric');
			app.DistractorAngleSpread_DMTS.Enable = 'off';
			app.DistractorAngleSpread_DMTS.Value = 60;
			app.TargetYPositionLabel_6 = uilabel(app.GridLayout9_5);
			app.TargetYPositionLabel_6.Enable = 'off';
			app.TargetYPositionLabel_6.Text = 'Object center angle (°)';
			app.DistractorCenterAngle_DMTS = uieditfield(app.GridLayout9_5, 'numeric');
			app.DistractorCenterAngle_DMTS.Enable = 'off';
			app.DistractorCenterAngle_DMTS.Value = -90;
			app.THINGSTab = uitab(app.TabGroup);
			app.THINGSTab.Title = '📸 THINGS';
			app.GridLayout13_2 = uigridlayout(app.THINGSTab);
			app.THINGSOddOneOutOOOTaskPanel = uipanel(app.GridLayout13_2);
			app.THINGSOddOneOutOOOTaskPanel.Title = 'THINGS Odd-One-Out (OOO) Task';
			app.GridLayout9_8 = uigridlayout(app.THINGSOddOneOutOOOTaskPanel);
			app.ObjectSizeEditFieldLabel_2 = uilabel(app.GridLayout9_8);
			app.ObjectSizeEditFieldLabel_2.Text = 'Object Size (°)';
			app.ObjectSize_OOO = uieditfield(app.GridLayout9_8, 'numeric');
			app.ObjectSize_OOO.Value = 8;
			app.ObjectDistanceLabel = uilabel(app.GridLayout9_8);
			app.ObjectDistanceLabel.Text = 'Object Distance from init';
			app.TrialTimeEditField_2Label_2 = uilabel(app.GridLayout9_8);
			app.TrialTimeEditField_2Label_2.Text = 'Trial Time (s)';
			app.TrialTime_OOO = uieditfield(app.GridLayout9_8, 'numeric');
			app.TrialTime_OOO.Value = 15;
			app.TaskStageDropDownLabel_2 = uilabel(app.GridLayout9_8);
			app.TaskStageDropDownLabel_2.Text = 'Task Stage';
			app.TaskStage_OOO = uidropdown(app.GridLayout9_8);
			app.TaskStage_OOO.Items = {'Training 1', 'Training 2', 'Training 3', 'Full Task'};
			app.TaskStage_OOO.Value = 'Training 1';
			app.ObjectSetLabel = uilabel(app.GridLayout9_8);
			app.ObjectSetLabel.Text = 'Training Set';
			app.ObjectType_OOO = uidropdown(app.GridLayout9_8);
			app.ObjectType_OOO.Items = {'Set A', 'Set B'};
			app.ObjectType_OOO.Value = 'Set A';
			app.STARTTHINGSButton = uibutton(app.GridLayout9_8, 'push');
			app.STARTTHINGSButton.ButtonPushedFcn = createCallbackFcn(app, @STARTOOO, true);
			app.STARTTHINGSButton.Tag = 'touchTraining';
			app.STARTTHINGSButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.STARTTHINGSButton.Text = 'START THINGS';
			app.StopButton_OOO = uibutton(app.GridLayout9_8, 'push');
			app.StopButton_OOO.ButtonPushedFcn = createCallbackFcn(app, @STOPButton, true);
			app.StopButton_OOO.Tag = 'touchTraining';
			app.StopButton_OOO.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_OOO.Text = 'Stop';
			app.InitiationPointPositionxyEditField_2Label_2 = uilabel(app.GridLayout9_8);
			app.InitiationPointPositionxyEditField_2Label_2.Text = 'Initiation Point Position (x,y)';
			app.InitiationPointPositionxy_OOO = uieditfield(app.GridLayout9_8, 'text');
			app.InitiationPointPositionxy_OOO.Value = '[ 0 6 ]';
			app.InitiationPointColourEditField_2Label_2 = uilabel(app.GridLayout9_8);
			app.InitiationPointColourEditField_2Label_2.Text = 'Initiation Point Colour';
			app.InitiationPointColour_OOO = uieditfield(app.GridLayout9_8, 'text');
			app.InitiationPointColour_OOO.Value = '[ 1 0.4 0.3 ]';
			app.InitiationPointSizeEditField_2Label_2 = uilabel(app.GridLayout9_8);
			app.InitiationPointSizeEditField_2Label_2.Text = 'Initiation Point Size';
			app.InitiationPointSize_OOO = uieditfield(app.GridLayout9_8, 'numeric');
			app.InitiationPointSize_OOO.Value = 7;
			app.TargetYPositionLabel = uilabel(app.GridLayout9_8);
			app.TargetYPositionLabel.Text = 'Object spread (°) around init';
			app.InitiationHoldTimesLabel = uilabel(app.GridLayout9_8);
			app.InitiationHoldTimesLabel.Text = 'Initiation Hold Time (s)';
			app.InitiationHoldTime_OOO = uieditfield(app.GridLayout9_8, 'numeric');
			app.InitiationHoldTime_OOO.Value = 0.2;
			app.TargetHoldTimesLabel = uilabel(app.GridLayout9_8);
			app.TargetHoldTimesLabel.Text = 'Target Hold Time (s)';
			app.TargetHoldTime_OOO = uieditfield(app.GridLayout9_8, 'numeric');
			app.TargetHoldTime_OOO.Value = 0.05;
			app.StatusButton_OOO = uibutton(app.GridLayout9_8, 'push');
			app.StatusButton_OOO.ButtonPushedFcn = createCallbackFcn(app, @STATUSButton, true);
			app.StatusButton_OOO.Tag = 'touchTraining';
			app.StatusButton_OOO.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton_OOO.Text = 'Status?';
			app.ChangeResource_OOO = uibutton(app.GridLayout9_8, 'push');
			app.ChangeResource_OOO.Icon = fullfile(pathToMLAPP, 'ui', 'folder-outline.svg');
			app.ChangeResource_OOO.Text = 'Change Resource Folder';
			app.folder_OOO = uieditfield(app.GridLayout9_8, 'text');
			app.folder_OOO.Tooltip = {'Folder where task resources are stored'};
			app.folder_OOO.Value = '~/Code/CageLab-Code/resources/things';
			app.TargetHoldTimesLabel_2 = uilabel(app.GridLayout9_8);
			app.TargetHoldTimesLabel_2.Text = 'Restrict Images Per Category';
			app.equalizeN_OOO = uieditfield(app.GridLayout9_8, 'numeric');
			app.equalizeN_OOO.Limits = [0 1000];
			app.equalizeN_OOO.RoundFractionalValues = 'on';
			app.equalizeN_OOO.ValueDisplayFormat = '%i';
			app.equalizeN_OOO.Value = 4;
			app.TargetHoldTimesLabel_3 = uilabel(app.GridLayout9_8);
			app.TargetHoldTimesLabel_3.Text = 'Pedestal Opacity';
			app.equalizeN_OOO_2 = uieditfield(app.GridLayout9_8, 'numeric');
			app.equalizeN_OOO_2.Limits = [0 1];
			app.equalizeN_OOO_2.ValueDisplayFormat = '%.2f';
			app.equalizeN_OOO_2.Value = 0.75;
			app.TargetHoldTimesLabel_4 = uilabel(app.GridLayout9_8);
			app.TargetHoldTimesLabel_4.Text = 'Repeat Trials %';
			app.RepeatTrialsPercent_OOO = uieditfield(app.GridLayout9_8, 'numeric');
			app.RepeatTrialsPercent_OOO.Limits = [0 1000];
			app.RepeatTrialsPercent_OOO.RoundFractionalValues = 'on';
			app.RepeatTrialsPercent_OOO.ValueDisplayFormat = '%i';
			app.RepeatTrialsPercent_OOO.Value = 5;
			app.TargetHoldTimesLabel_5 = uilabel(app.GridLayout9_8);
			app.TargetHoldTimesLabel_5.Text = 'Catch Trial Every #';
			app.CatchTrials_OOO = uieditfield(app.GridLayout9_8, 'numeric');
			app.CatchTrials_OOO.Limits = [0 1000];
			app.CatchTrials_OOO.RoundFractionalValues = 'on';
			app.CatchTrials_OOO.ValueDisplayFormat = '%i';
			app.CatchTrials_OOO.Value = 20;
			app.TargetHoldTimesLabel_6 = uilabel(app.GridLayout9_8);
			app.TargetHoldTimesLabel_6.Text = 'Default Distractor Opacity';
			app.DistractorOpacity_OOO = uieditfield(app.GridLayout9_8, 'numeric');
			app.DistractorOpacity_OOO.Value = 1;
			app.TargetHoldTimesLabel_7 = uilabel(app.GridLayout9_8);
			app.TargetHoldTimesLabel_7.Text = 'Default Pedestal Opacity';
			app.PedestalOpacity_OOO = uieditfield(app.GridLayout9_8, 'numeric');
			app.Phase138Label_2 = uilabel(app.GridLayout9_8);
			app.Phase138Label_2.Text = 'Training Phase (1 - 20)';
			app.Phase_OOO = uieditfield(app.GridLayout9_8, 'numeric');
			app.Phase_OOO.Limits = [1 38];
			app.Phase_OOO.RoundFractionalValues = 'on';
			app.Phase_OOO.Value = 1;
			app.UseStaircase_OOO = uicheckbox(app.GridLayout9_8);
			app.UseStaircase_OOO.Text = 'Use Staircase for Training';
			app.UseStaircase_OOO.Value = true;
			app.easyMode_OOO = uicheckbox(app.GridLayout9_8);
			app.easyMode_OOO.Text = 'Training 3 Easy Mode';
			app.easyMode_OOO.Value = true;
			app.simplified_OOO = uicheckbox(app.GridLayout9_8);
			app.simplified_OOO.Text = 'Simplified Animate/Inanimate';
			app.ObjectSeparation_OOO = uieditfield(app.GridLayout9_8, 'numeric');
			app.ObjectSeparation_OOO.Value = 12;
			app.DistractorAngleSpread_OOO = uieditfield(app.GridLayout9_8, 'numeric');
			app.DistractorAngleSpread_OOO.Value = 60;
			app.TargetYPositionLabel_2 = uilabel(app.GridLayout9_8);
			app.TargetYPositionLabel_2.Text = 'Object center angle (°)';
			app.DistractorCenterAngle_OOO = uieditfield(app.GridLayout9_8, 'numeric');
			app.DistractorCenterAngle_OOO.Value = -90;
			app.PacmanTask = uitab(app.TabGroup);
			app.PacmanTask.Title = '🕹️ Pacman';
			app.PacmanTask.ButtonDownFcn = createCallbackFcn(app, @PacmanTaskButtonDown, true);
			app.GridLayout15 = uigridlayout(app.PacmanTask);
			app.PacmanTaskPMTPanel = uipanel(app.GridLayout15);
			app.PacmanTaskPMTPanel.Title = '🕹️ Pacman Task (PMT)';
			app.GridLayout9_6 = uigridlayout(app.PacmanTaskPMTPanel);
			app.MapLabel = uilabel(app.GridLayout9_6);
			app.MapLabel.Text = '🗺️ Map ';
			app.Map_PMT = uidropdown(app.GridLayout9_6);
			app.Map_PMT.Items = {'GenerateRandomMap_1_T_random_dir', 'GenerateRandomMap_L_simple'};
			app.Map_PMT.Value = 'GenerateRandomMap_1_T_random_dir';
			app.STARTPMTButton = uibutton(app.GridLayout9_6, 'push');
			app.STARTPMTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTPMT, true);
			app.STARTPMTButton.Tag = 'touchTraining';
			app.STARTPMTButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.STARTPMTButton.Text = 'START  PMT';
			app.StopButton_PMT = uibutton(app.GridLayout9_6, 'push');
			app.StopButton_PMT.ButtonPushedFcn = createCallbackFcn(app, @STOPButton, true);
			app.StopButton_PMT.Tag = 'touchTraining';
			app.StopButton_PMT.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_PMT.Text = 'Stop';
			app.StatusButton_PMT = uibutton(app.GridLayout9_6, 'push');
			app.StatusButton_PMT.ButtonPushedFcn = createCallbackFcn(app, @STATUSButton, true);
			app.StatusButton_PMT.Tag = 'touchTraining';
			app.StatusButton_PMT.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton_PMT.Text = 'Status?';
			app.Note_PMT = uilabel(app.GridLayout9_6);
			app.Note_PMT.Text = 'Note: the Pacman task uses its own display settings and so the [⚙️ Main] general display settings do not apply to this task. You must edit the Pacman code itself to make adjustments for the moment... This interface allows you to launch the Pacman task and set the map to be tested, and also configure the subject name, lab, user etc. data settings used for saving the data and conneting to Alyx.';
			app.RewardBeepsSwitchLabel = uilabel(app.GridLayout9_6);
			app.RewardBeepsSwitchLabel.Text = '👂🏼 Reward Beeps?';
			app.RewardBeeps_PMT = uiswitch(app.GridLayout9_6, 'slider');
			app.ForceResolutionLabel = uilabel(app.GridLayout9_6);
			app.ForceResolutionLabel.Text = '📺 Force Resolution';
			app.ForceResolution_PMT = uiswitch(app.GridLayout9_6, 'slider');
			app.ForceResolution_PMT.Value = 'On';
			app.GetPath_PMT = uibutton(app.GridLayout9_6, 'push');
			app.GetPath_PMT.ButtonPushedFcn = createCallbackFcn(app, @GetPath_PMTPushed, true);
			app.GetPath_PMT.Icon = fullfile(pathToMLAPP, 'ui', 'folder-outline.svg');
			app.GetPath_PMT.Text = '';
			app.MapsPath_PMT = uieditfield(app.GridLayout9_6, 'text');
			app.MapsPath_PMT.Value = '~/Code/PacmanTask/Maps';
			app.Phase138Label_3 = uilabel(app.GridLayout9_6);
			app.Phase138Label_3.Enable = 'off';
			app.Phase138Label_3.Text = 'Joystick Delay (s)';
			app.JoyStickDelay_PMT = uieditfield(app.GridLayout9_6, 'numeric');
			app.JoyStickDelay_PMT.Limits = [0 1];
			app.JoyStickDelay_PMT.Enable = 'off';
			app.JoyStickDelay_PMT.Value = 0.05;
			app.IED = uitab(app.TabGroup);
			app.IED.Title = '💠 IED';
			app.GridLayout_IED = uigridlayout(app.IED);
			app.IntraExtraDimensionalSetShiftingIEDPanel = uipanel(app.GridLayout_IED);
			app.IntraExtraDimensionalSetShiftingIEDPanel.Title = '🕹️ Intra-Extra Dimensional Set Shifting (IED)';
			app.GridLayout_IED2 = uigridlayout(app.IntraExtraDimensionalSetShiftingIEDPanel);
			app.STARTIEDButton = uibutton(app.GridLayout_IED2, 'push');
			app.STARTIEDButton.ButtonPushedFcn = createCallbackFcn(app, @STARTIED, true);
			app.STARTIEDButton.Tag = 'touchTraining';
			app.STARTIEDButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.STARTIEDButton.Text = 'START  IED';
			app.StopButton_IED = uibutton(app.GridLayout_IED2, 'push');
			app.StopButton_IED.Tag = 'touchTraining';
			app.StopButton_IED.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_IED.Text = 'Stop';
			app.StatusButton_IED = uibutton(app.GridLayout_IED2, 'push');
			app.StatusButton_IED.Tag = 'touchTraining';
			app.StatusButton_IED.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton_IED.Text = 'Status?';
			app.Note_IED = uilabel(app.GridLayout_IED2);
			app.Note_IED.Text = 'This task is modified from CANTAB, there are several object dimensions. Subjects learn reward contingencies for A first, then the contingency switches to ~A (intradimensional set shift). Then A becomes irrelevant and set B becomes relevant (extradimensional set shift). sd  - Simple Discrimination: DIM 1 varies;   cd  - Compound Discrimination: 2 DIM sr  - Simple Reversal: same stimuli as SD; reward contingency reverses to test reversal learning.  cr  - Compound Reversal: same compound setup as CD; reward contingency reverses on the same relevant dimension.  ids - Intra-Dimensional Shift: new exemplars on both dimensions; relevant dimension stays the same (shape).  idr - Intra-Dimensional Reversal: same exemplars as IDS; contingency reverses within the same relevant dimension. eds - Extra-Dimensional Shift: new exemplars again; relevant dimension switches (shape -> color), testing set shifting. edr - Extra-Dimensional Reversal: same exemplars as EDS; contingency reverses after the extra-dimensional shift.';
			app.TaskStageDropDownLabel_3 = uilabel(app.GridLayout_IED2);
			app.TaskStageDropDownLabel_3.Text = 'Task Stage';
			app.TaskStage_IED = uidropdown(app.GridLayout_IED2);
			app.TaskStage_IED.Items = {'Training 1', 'Training 2', 'Training 3', 'Full Task'};
			app.TaskStage_IED.Value = 'Training 1';
			app.ObjectSetLabel_2 = uilabel(app.GridLayout_IED2);
			app.ObjectSetLabel_2.Text = 'Object Set';
			app.ObjectType_IED = uidropdown(app.GridLayout_IED2);
			app.ObjectType_IED.Items = {'Morphobes'};
			app.ObjectType_IED.Value = 'Morphobes';
			app.TaskStageDropDownLabel_6 = uilabel(app.GridLayout_IED2);
			app.TaskStageDropDownLabel_6.Text = '# Targets';
			app.Targets_IED = uidropdown(app.GridLayout_IED2);
			app.Targets_IED.Items = {'2', '4'};
			app.Targets_IED.Value = '2';
			app.TaskStageDropDownLabel_7 = uilabel(app.GridLayout_IED2);
			app.TaskStageDropDownLabel_7.Text = 'Intra-dimension';
			app.ID_IED = uidropdown(app.GridLayout_IED2);
			app.ID_IED.Items = {'Shape', 'Colour', 'Appendage', 'Texture'};
			app.ID_IED.Value = 'Shape';
			app.TaskType_IEDLabel = uilabel(app.GridLayout_IED2);
			app.TaskType_IEDLabel.Text = 'Task Order';
			app.TaskType_IED = uieditfield(app.GridLayout_IED2, 'text');
			app.TaskType_IED.Value = '[ "sd"  "sr"  "cd"  "cr"  "ids"  "idr"  "eds"  "edr" ]';
			app.TaskStageDropDownLabel_8 = uilabel(app.GridLayout_IED2);
			app.TaskStageDropDownLabel_8.Text = 'Extra-dimension';
			app.ED_IED = uidropdown(app.GridLayout_IED2);
			app.ED_IED.Items = {'Shape', 'Colour', 'Appendage', 'Texture'};
			app.ED_IED.Value = 'Colour';
			app.Phase138Label_4 = uilabel(app.GridLayout_IED2);
			app.Phase138Label_4.Text = 'Correct Criterion';
			app.criterion_IED = uieditfield(app.GridLayout_IED2, 'numeric');
			app.criterion_IED.Limits = [3 200];
			app.criterion_IED.RoundFractionalValues = 'on';
			app.criterion_IED.Value = 6;
			app.Phase138Label_5 = uilabel(app.GridLayout_IED2);
			app.Phase138Label_5.Text = 'Max Incorrect';
			app.MaxIncorrect_IED = uieditfield(app.GridLayout_IED2, 'numeric');
			app.MaxIncorrect_IED.Limits = [3 200];
			app.MaxIncorrect_IED.RoundFractionalValues = 'on';
			app.MaxIncorrect_IED.Value = 50;
			app.Phase138Label_6 = uilabel(app.GridLayout_IED2);
			app.Phase138Label_6.Text = 'Distractor 1 Default';
			app.distractorOne_IED = uieditfield(app.GridLayout_IED2, 'numeric');
			app.distractorOne_IED.Limits = [0 20];
			app.distractorOne_IED.RoundFractionalValues = 'on';
			app.distractorOne_IED.Value = 1;
			app.Phase138Label_7 = uilabel(app.GridLayout_IED2);
			app.Phase138Label_7.Text = 'Distractor 2 Default';
			app.distractorTwo_IED = uieditfield(app.GridLayout_IED2, 'numeric');
			app.distractorTwo_IED.Limits = [0 20];
			app.distractorTwo_IED.RoundFractionalValues = 'on';
			app.distractorTwo_IED.Value = 1;
			app.randomiseDistractors_IED = uicheckbox(app.GridLayout_IED2);
			app.randomiseDistractors_IED.Text = 'Randomise other dimensions?';
			app.distractors_IED = uicheckbox(app.GridLayout_IED2);
			app.distractors_IED.Text = 'Show other dimensions?';
			app.InitPosition_IEDLabel = uilabel(app.GridLayout_IED2);
			app.InitPosition_IEDLabel.Text = 'Init Point Position (x°,y°)';
			app.InitPosition_IED = uieditfield(app.GridLayout_IED2, 'text');
			app.InitPosition_IED.Value = '[ 0 6 ]';
			app.EditFieldLabel_2 = uilabel(app.GridLayout_IED2);
			app.EditFieldLabel_2.Text = 'Init Point Colour';
			app.InitColour_IED = uieditfield(app.GridLayout_IED2, 'text');
			app.InitColour_IED.Value = '[ 1 0.4 0.3 ]';
			app.InitiationPointSizeLabel_2 = uilabel(app.GridLayout_IED2);
			app.InitiationPointSizeLabel_2.Text = 'Init Point Size (°)';
			app.InitSize_IED = uieditfield(app.GridLayout_IED2, 'numeric');
			app.InitSize_IED.Value = 8;
			app.InitiationHoldTimesLabel_3 = uilabel(app.GridLayout_IED2);
			app.InitiationHoldTimesLabel_3.Text = 'Init Hold Time (s)';
			app.InitHoldTime_IED = uieditfield(app.GridLayout_IED2, 'numeric');
			app.InitHoldTime_IED.Value = 0.03;
			app.TargetYPositionLabel_3 = uilabel(app.GridLayout_IED2);
			app.TargetYPositionLabel_3.Text = 'Object spread (°) around init';
			app.DistractorAngleSpread_IED = uieditfield(app.GridLayout_IED2, 'numeric');
			app.DistractorAngleSpread_IED.Value = 70;
			app.TargetYPositionLabel_4 = uilabel(app.GridLayout_IED2);
			app.TargetYPositionLabel_4.Text = 'Object angle center (°)';
			app.DistractorCenterAngle_IED = uieditfield(app.GridLayout_IED2, 'numeric');
			app.DistractorCenterAngle_IED.Value = -90;
			app.ObjectSizeEditFieldLabel_3 = uilabel(app.GridLayout_IED2);
			app.ObjectSizeEditFieldLabel_3.Text = 'Object Size (°)';
			app.ObjectSize_IED = uieditfield(app.GridLayout_IED2, 'numeric');
			app.ObjectSize_IED.Value = 8;
			app.ObjectDistanceLabel_2 = uilabel(app.GridLayout_IED2);
			app.ObjectDistanceLabel_2.Text = 'Object Distance from init';
			app.ObjectSeparation_IED = uieditfield(app.GridLayout_IED2, 'numeric');
			app.ObjectSeparation_IED.Value = 12;
			app.TBIPTab = uitab(app.TabGroup);
			app.TBIPTab.Title = '⌚ TBIP';
			app.TimeBasedProtocolPanel = uipanel(app.TBIPTab);
			app.TimeBasedProtocolPanel.Enable = 'off';
			app.TimeBasedProtocolPanel.Title = '⌚ Time-Based Inference Paradigm (TBIP)';
			app.TimeBasedProtocolPanel.Tag = 'TBIP';
			app.GridLayout9_10 = uigridlayout(app.TimeBasedProtocolPanel);
			app.STARTTBIPButton = uibutton(app.GridLayout9_10, 'push');
			app.STARTTBIPButton.Tag = 'TBIP';
			app.STARTTBIPButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.STARTTBIPButton.Enable = 'off';
			app.STARTTBIPButton.Text = 'START  TBIP';
			app.StopButton_TBIP = uibutton(app.GridLayout9_10, 'push');
			app.StopButton_TBIP.Tag = 'TBIP';
			app.StopButton_TBIP.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_TBIP.Enable = 'off';
			app.StopButton_TBIP.Text = 'Stop';
			app.StatusButton_TBIP = uibutton(app.GridLayout9_10, 'push');
			app.StatusButton_TBIP.Tag = 'TBIP';
			app.StatusButton_TBIP.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton_TBIP.Enable = 'off';
			app.StatusButton_TBIP.Text = 'Status?';
			app.Note_TBIP = uilabel(app.GridLayout9_10);
			app.Note_TBIP.Tag = 'TBIP';
			app.Note_TBIP.Text = 'Sajad Lab Protocol: install the git repo to activate this panel';
			app.RewardBeepsSwitchLabel_3 = uilabel(app.GridLayout9_10);
			app.RewardBeepsSwitchLabel_3.Text = '👂🏼 Reward Beeps?';
			app.RewardBeeps_TBIP = uiswitch(app.GridLayout9_10, 'slider');
			app.RewardBeeps_TBIP.Tag = 'TBIP';
			app.EyeLinkLabel = uilabel(app.GridLayout9_10);
			app.EyeLinkLabel.Text = '👁️ EyeLink?';
			app.RewardBeeps2_TBIP = uiswitch(app.GridLayout9_10, 'slider');
			app.RewardBeeps2_TBIP.Tag = 'TBIP';
			app.TaskStageDropDownLabel_4 = uilabel(app.GridLayout9_10);
			app.TaskStageDropDownLabel_4.Text = 'Task Stage';
			app.TaskStage_IED_2 = uidropdown(app.GridLayout9_10);
			app.TaskStage_IED_2.Items = {'Training 1', 'Training 2', 'Training 3', 'Full Task'};
			app.TaskStage_IED_2.Value = 'Training 1';
			app.StateMachineTaskLabel = uilabel(app.GridLayout9_10);
			app.StateMachineTaskLabel.Text = 'State Machine Task';
			app.TaskStage_IED_4 = uidropdown(app.GridLayout9_10);
			app.TaskStage_IED_4.Items = {'Paradigm 1', 'Paradigm 2'};
			app.TaskStage_IED_4.Value = 'Paradigm 1';
			app.OddballTab = uitab(app.TabGroup);
			app.OddballTab.Title = '😲 Oddball';
			app.TimeBasedProtocolPanel_2 = uipanel(app.OddballTab);
			app.TimeBasedProtocolPanel_2.Enable = 'off';
			app.TimeBasedProtocolPanel_2.Title = '😲 Visual Oddball task';
			app.TimeBasedProtocolPanel_2.Tag = 'TBIP';
			app.GridLayout9_11 = uigridlayout(app.TimeBasedProtocolPanel_2);
			app.STARTOddballButton = uibutton(app.GridLayout9_11, 'push');
			app.STARTOddballButton.Tag = 'TBIP';
			app.STARTOddballButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.STARTOddballButton.Text = 'START  Oddball';
			app.StopButton_TBIP_2 = uibutton(app.GridLayout9_11, 'push');
			app.StopButton_TBIP_2.Tag = 'TBIP';
			app.StopButton_TBIP_2.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_TBIP_2.Text = 'Stop';
			app.StatusButton_TBIP_2 = uibutton(app.GridLayout9_11, 'push');
			app.StatusButton_TBIP_2.Tag = 'TBIP';
			app.StatusButton_TBIP_2.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton_TBIP_2.Text = 'Status?';
			app.Note_TBIP_2 = uilabel(app.GridLayout9_11);
			app.Note_TBIP_2.Tag = 'TBIP';
			app.Note_TBIP_2.Text = 'A visual oddball task';
			app.RewardBeepsSwitchLabel_4 = uilabel(app.GridLayout9_11);
			app.RewardBeepsSwitchLabel_4.Text = '👂🏼 Reward Beeps?';
			app.RewardBeeps_TBIP_2 = uiswitch(app.GridLayout9_11, 'slider');
			app.RewardBeeps_TBIP_2.Tag = 'TBIP';
			app.TaskStageDropDownLabel_5 = uilabel(app.GridLayout9_11);
			app.TaskStageDropDownLabel_5.Text = 'Task Stage';
			app.TaskStage_IED_3 = uidropdown(app.GridLayout9_11);
			app.TaskStage_IED_3.Items = {'Training 1', 'Training 2', 'Training 3', 'Full Task'};
			app.TaskStage_IED_3.Value = 'Training 1';
			app.GoukiCATTab = uitab(app.TabGroup);
			app.GoukiCATTab.Title = '🐃 Gouki-CAT';
			app.GridLayout16 = uigridlayout(app.GoukiCATTab);
			app.DragCategorisationDCATPanel = uipanel(app.GridLayout16);
			app.DragCategorisationDCATPanel.Title = 'Drag Categorisation (DCAT)';
			app.GridLayout9_4 = uigridlayout(app.DragCategorisationDCATPanel);
			app.InterTrialIntervalEditFieldLabel = uilabel(app.GridLayout9_4);
			app.InterTrialIntervalEditFieldLabel.Text = 'Inter Trial Interval';
			app.InterTrialIntervalEditField = uieditfield(app.GridLayout9_4, 'numeric');
			app.InterTrialIntervalEditField.Value = 2;
			app.InitiationPointPositionxyEditFieldLabel = uilabel(app.GridLayout9_4);
			app.InitiationPointPositionxyEditFieldLabel.Text = 'Initiation Point Position (x,y)';
			app.InitiationPointPositionxyEditField = uieditfield(app.GridLayout9_4, 'text');
			app.InitiationPointPositionxyEditField.Value = '[ 0 6 ]';
			app.TrialTimeEditField_7Label = uilabel(app.GridLayout9_4);
			app.TrialTimeEditField_7Label.Text = 'Trial Time';
			app.TrialTimeEditField_7 = uieditfield(app.GridLayout9_4, 'numeric');
			app.TrialTimeEditField_7.Value = 5;
			app.TaskStageDropDown_3Label = uilabel(app.GridLayout9_4);
			app.TaskStageDropDown_3Label.Text = 'Task Stage';
			app.TaskStageDropDown_3 = uidropdown(app.GridLayout9_4);
			app.TaskStageDropDown_3.Items = {'Training 1', 'Training 2', 'Training 3', 'Full Task'};
			app.TaskStageDropDown_3.Value = 'Full Task';
			app.ObjectTypeDropDown_3Label = uilabel(app.GridLayout9_4);
			app.ObjectTypeDropDown_3Label.Text = 'Object Type';
			app.ObjectTypeDropDown_3 = uidropdown(app.GridLayout9_4);
			app.ObjectTypeDropDown_3.Items = {'Animal/Object'};
			app.ObjectTypeDropDown_3.Value = 'Animal/Object';
			app.STARTDCATButton = uibutton(app.GridLayout9_4, 'push');
			app.STARTDCATButton.ButtonPushedFcn = createCallbackFcn(app, @STARTDCATB, true);
			app.STARTDCATButton.Tag = 'touchTraining';
			app.STARTDCATButton.Icon = fullfile(pathToMLAPP, 'ui', 'start.svg');
			app.STARTDCATButton.Text = 'START  DCAT';
			app.InitiationPointColourEditFieldLabel = uilabel(app.GridLayout9_4);
			app.InitiationPointColourEditFieldLabel.Text = 'Initiation Point Colour';
			app.InitiationPointColourEditField = uieditfield(app.GridLayout9_4, 'text');
			app.InitiationPointColourEditField.Value = '[ 1 0.4 0.3 ]';
			app.TargetPosition1EditFieldLabel = uilabel(app.GridLayout9_4);
			app.TargetPosition1EditFieldLabel.Text = 'Target Position 1';
			app.TargetPosition1EditField = uieditfield(app.GridLayout9_4, 'text');
			app.TargetPosition1EditField.Value = '[ -9 -6 ]';
			app.TargetPosition2EditFieldLabel = uilabel(app.GridLayout9_4);
			app.TargetPosition2EditFieldLabel.Text = 'Target Position 2';
			app.TargetPosition2EditField = uieditfield(app.GridLayout9_4, 'text');
			app.TargetPosition2EditField.Value = '[ 9 -6 ]';
			app.ObjectSizeEditField_2Label = uilabel(app.GridLayout9_4);
			app.ObjectSizeEditField_2Label.Text = 'Object Size';
			app.ObjectSizeEditField_2 = uieditfield(app.GridLayout9_4, 'numeric');
			app.ObjectSizeEditField_2.Value = 8;
			app.InitiationPointSizeEditFieldLabel = uilabel(app.GridLayout9_4);
			app.InitiationPointSizeEditFieldLabel.Text = 'Initiation Point Size';
			app.InitiationPointSizeEditField = uieditfield(app.GridLayout9_4, 'numeric');
			app.InitiationPointSizeEditField.Value = 6;
			app.StopButton_8 = uibutton(app.GridLayout9_4, 'push');
			app.StopButton_8.ButtonPushedFcn = createCallbackFcn(app, @STOPButton, true);
			app.StopButton_8.Tag = 'touchTraining';
			app.StopButton_8.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_8.Text = 'Stop';
			app.StatusButton_4 = uibutton(app.GridLayout9_4, 'push');
			app.StatusButton_4.ButtonPushedFcn = createCallbackFcn(app, @STATUSButton, true);
			app.StatusButton_4.Tag = 'touchTraining';
			app.StatusButton_4.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton_4.Text = 'Status?';
			app.ICATab = uitab(app.TabGroup);
			app.ICATab.Title = '🐄 ICA';
			app.GridLayout17 = uigridlayout(app.ICATab);
			app.PatchForagingTab = uitab(app.TabGroup);
			app.PatchForagingTab.Title = '🎋 Patch Foraging';
			app.GridLayout17_2 = uigridlayout(app.PatchForagingTab);
			app.ThresholdsTab = uitab(app.TabGroup);
			app.ThresholdsTab.Title = '💡  Thresholds';
			app.GridLayout17_3 = uigridlayout(app.ThresholdsTab);
			app.GridLayoutLOG = uigridlayout(app.GridLayoutMAIN);
			app.Log = uitextarea(app.GridLayoutLOG);
			app.Log.Placeholder = 'Log';
			app.Image = uiimage(app.GridLayoutLOG);
			app.Image.ImageSource = fullfile(pathToMLAPP, 'ui', 'cagelab.png');
			app.IPGlobal = uidropdown(app.GridLayoutLOG);
			app.IPGlobal.Items = {'127.0.0.1 localhost'};
			app.IPGlobal.ValueChangedFcn = createCallbackFcn(app, @IPGlobalValueChanged, true);
			app.IPGlobal.Tooltip = {'The remote system IP address'};
			app.IPGlobal.Value = '127.0.0.1 localhost';
			app.subjectNameGlobal = uieditfield(app.GridLayoutLOG, 'text');
			app.subjectNameGlobal.ValueChangedFcn = createCallbackFcn(app, @subjectNameGlobalValueChanged, true);
			app.subjectNameGlobal.Tooltip = {'Subject Name'};
			app.subjectNameGlobal.Value = 'Simulcra';
			app.StatusButton_MAIN = uibutton(app.GridLayoutLOG, 'push');
			app.StatusButton_MAIN.ButtonPushedFcn = createCallbackFcn(app, @STATUSButton, true);
			app.StatusButton_MAIN.Tag = 'touchTraining';
			app.StatusButton_MAIN.Icon = fullfile(pathToMLAPP, 'ui', 'info.svg');
			app.StatusButton_MAIN.Tooltip = {'Get remote Session Status'};
			app.StatusButton_MAIN.Text = 'Status';
			app.PingButton_MAIN = uibutton(app.GridLayoutLOG, 'push');
			app.PingButton_MAIN.ButtonPushedFcn = createCallbackFcn(app, @PingButtonPushed, true);
			app.PingButton_MAIN.Icon = fullfile(pathToMLAPP, 'ui', 'pin.svg');
			app.PingButton_MAIN.Tooltip = {'Ping Remote Device'};
			app.PingButton_MAIN.Text = 'Ping';
			app.StopButton_MAIN = uibutton(app.GridLayoutLOG, 'push');
			app.StopButton_MAIN.ButtonPushedFcn = createCallbackFcn(app, @STOPButton, true);
			app.StopButton_MAIN.Tag = 'touchTraining';
			app.StopButton_MAIN.Icon = fullfile(pathToMLAPP, 'ui', 'stop.svg');
			app.StopButton_MAIN.Tooltip = {'Stop Session if Running'};
			app.StopButton_MAIN.Text = 'Stop';
			app.statusLamp = uilamp(app.GridLayoutLOG);
			app.statusLamp.Tooltip = {'cogmoteGO status whether this cagelab is running a task'};
			app.ContextMenu = uicontextmenu(app.CageLabGUIUIFigure);
			app.sessionssubjecttest02Menu = uimenu(app.ContextMenu);
			app.sessionssubjecttest02Menu.Text = 'sessions?subject=test02';
			app.weighingslimit10Menu = uimenu(app.ContextMenu);
			app.weighingslimit10Menu.Text = 'weighings?limit=10';
			app.subjectsMenu = uimenu(app.ContextMenu);
			app.subjectsMenu.Text = 'subjects';
			% Show the figure after all components are created
			app.CageLabGUIUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = CageLab

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.CageLabGUIUIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.CageLabGUIUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.CageLabGUIUIFigure)
		end
	end
end